classdef PitAnalyzerApp2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ImageProcessingTab              matlab.ui.container.Tab
        RightPanel                      matlab.ui.container.Panel
        Image                           matlab.ui.control.Image
        Hyperlink                       matlab.ui.control.Hyperlink
        YoavBenDorButton                matlab.ui.control.Button
        EladBenDorButton                matlab.ui.control.Button
        Messageboard200mostrecentPanel  matlab.ui.container.Panel
        MessageboardTextArea            matlab.ui.control.TextArea
        CenterPanel                     matlab.ui.container.Panel
        ApproveanalysesButtonGroup      matlab.ui.container.ButtonGroup
        unknownButton                   matlab.ui.control.ToggleButton
        notapprovedButton               matlab.ui.control.ToggleButton
        approvedButton                  matlab.ui.control.ToggleButton
        nclustersSpinner                matlab.ui.control.Spinner
        nclustersSpinnerLabel           matlab.ui.control.Label
        ScalePanel                      matlab.ui.container.Panel
        pixelscmLabel                   matlab.ui.control.Label
        scaleisSwitch                   matlab.ui.control.Switch
        scaleisSwitchLabel              matlab.ui.control.Label
        ImagescaleEditField             matlab.ui.control.NumericEditField
        ImagescaleEditFieldLabel        matlab.ui.control.Label
        analyzeimagesButton             matlab.ui.control.Button
        ReadimagesLabel_2               matlab.ui.control.Label
        LeftPanel                       matlab.ui.container.Panel
        fileformatEditField             matlab.ui.control.EditField
        filenameLabel                   matlab.ui.control.Label
        setfolderButton                 matlab.ui.control.Button
        SetfolderLabel                  matlab.ui.control.Label
        SamplenameEditField             matlab.ui.control.EditField
        SamplenameEditFieldLabel        matlab.ui.control.Label
        ImageListUITable                matlab.ui.control.Table
        imagetypesListBox               matlab.ui.control.ListBox
        imagetypesListBoxLabel          matlab.ui.control.Label
        readimagesButton                matlab.ui.control.Button
        ReadimagesLabel                 matlab.ui.control.Label
        SynthesisTab                    matlab.ui.container.Tab
        LeftPanel_3                     matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        LeftPanel_2                     matlab.ui.container.Panel
        shapeDropDown                   matlab.ui.control.DropDown
        shapeDropDownLabel              matlab.ui.control.Label
        colorDropDown                   matlab.ui.control.DropDown
        colorDropDownLabel              matlab.ui.control.Label
        categoryDropDown                matlab.ui.control.DropDown
        categoryLabel                   matlab.ui.control.Label
        variableDropDown                matlab.ui.control.DropDown
        variableDropDownLabel           matlab.ui.control.Label
        mergerowsbyCheckBox             matlab.ui.control.CheckBox
        breakButton                     matlab.ui.control.Button
        PC2DropDown                     matlab.ui.control.DropDown
        PC2DropDownLabel                matlab.ui.control.Label
        PC1DropDown                     matlab.ui.control.DropDown
        PC1DropDownLabel                matlab.ui.control.Label
        clearselectionButton_2          matlab.ui.control.Button
        clearselectionButton            matlab.ui.control.Button
        AnalysesKnobLabel               matlab.ui.control.Label
        selectallButton_3               matlab.ui.control.Button
        targetsListBoxLabel             matlab.ui.control.Label
        setfolderButton_2               matlab.ui.control.Button
        savemergeddataButton            matlab.ui.control.Button
        setfolderButton_3               matlab.ui.control.Button
        SetfolderLabel_3                matlab.ui.control.Label
        loadCSVsButton                  matlab.ui.control.Button
        ReferencesListBox               matlab.ui.control.ListBox
        referencesListBoxLabel          matlab.ui.control.Label
        TargetsListBox                  matlab.ui.control.ListBox
        selectallButton_2               matlab.ui.control.Button
        AnalysesKnob                    matlab.ui.control.DiscreteKnob
        plotButton                      matlab.ui.control.Button
        SetfolderLabel_2                matlab.ui.control.Label
        FeaturesListBox                 matlab.ui.control.ListBox
        FeaturesListBoxLabel            matlab.ui.control.Label
        exportallfiguresButton          matlab.ui.control.Button
    end


    % preallocate variables for working
    properties (Access = private)
        % initialize structures for sharing data between callbacks
    end

    properties (Access = public)
        app_data=struct('defaults', struct());
        defaults=struct();

    end

    methods (Access = private)

        function [] = update_board(app,message)
            % improve functionality
            disp(datetime('now'))
            disp(message);
            oldmsgs=cellstr((app.MessageboardTextArea.Value));
            % adjusted to maintain only the last 200 messages in order to
            %set(app.MessageboardTextArea,'Value',[string(datetime('now'));message;'';oldmsgs]);
            set(app.MessageboardTextArea,'Value',[string(datetime('now'));message;'';oldmsgs(1:min(length(oldmsgs),200))]);
            drawnow
        end

        function [app] = select_working_folder(app)

            current_selected_dir = uigetdir(); % ask user for data directory
            folder_type = app.app_data.folder_type;

            if strcmp(folder_type,'working_folder')
                user_dir_path = current_selected_dir;
                user_dir_path_only_folder = user_dir_path(max(regexp(user_dir_path,'\'))+1:end);


                app.app_data.user_dir_path = user_dir_path;
                app.app_data.user_dir_path_only_folder = user_dir_path_only_folder;

                % if the selected folder is the one with the results of the
                % references
            elseif strcmp(folder_type,'references_folder')
                user_data_references_dir_path = current_selected_dir;
                user_data_references_dir_path_only_folder = user_data_references_dir_path(max(regexp(user_data_references_dir_path,'\'))+1:end);

                app.app_data.user_data_references_dir_path = user_data_references_dir_path;
                app.app_data.user_data_references_dir_path_only_folder = user_data_references_dir_path_only_folder;

                % if the selected folder is the one with the results of the
                % targets
            elseif strcmp(folder_type,'targets_folder')
                user_data_targets_dir_path = current_selected_dir;
                user_data_targets_dir_path_only_folder = user_data_targets_dir_path(max(regexp(user_data_targets_dir_path,'\'))+1:end);

                app.app_data.user_data_targets_dir_path = user_data_targets_dir_path;
                app.app_data.user_data_targets_dir_path_only_folder = user_data_targets_dir_path_only_folder;
            end
        end

        function [app] = select_data_folder(app)
            user_dir_path = uigetdir(); % ask user for data directory
            user_dir_path_only_folder = user_dir_path(max(regexp(user_dir_path,'\'))+1:end);

            app.app_data.user_data_dir_path = user_dir_path;
            app.app_data.user_data_dir_path_only_folder = user_dir_path_only_folder;
        end

        function [app] = preallocate_variables(app)
            %% preallocate variables
            1+1
            %% check if a CSV file exists
            stats_dir_path = app.app_data.stats_dir_path;
            user_dir_path =  app.app_data.user_dir_path;

            csvfiles = dir(fullfile(stats_dir_path, '**', '*.csv'));
            %  csvfiles_cell = fullfile({csvfiles.folder}, {csvfiles.name});

            % look for existing CSV files
            % csvfiles = dir(stats_dir_path, '*.CSV');
            csvfiles_table = struct2table(csvfiles);

            % look for existing MAT files
            %            matfiles = dir(stats_dir_path, '*.MAT');
            matfiles = dir(fullfile(stats_dir_path, '**','*.mat'));
            matfiles_table = struct2table(matfiles);

            % if no csv was found
            if isempty(csvfiles) % no csv file was found
                message = 'no csv datafile found';
                load_csv_file = 0;
                update_board(app,message);

                % more than one csv was found
            else % some csv files were found
                TF = contains(csvfiles_table.name,"pit_analyzer_stats_results",'IgnoreCase',true);
                if sum(sum(TF)) > 1

                    % if more than file was found stop and notify user
                    message = ['multiple csv data files were found\t' ...
                        'remove excess files and leave only 1 in folder'];
                    load_csv_file = 0;
                    error(message);

                    % a single csv file was found
                elseif sum(sum(TF)) == 1
                    message = strjoin({'a csv data file:', csvfiles_table.name ,'was found'},' ');

                    pause(1)
                    % ask user if the file should be loaded
                    answer = questdlg(strjoin({'Would you like to load file:',csvfiles_table.name,'?'},' '), ...
                        'user dialog', ...
                        'Yes','No','Yes');
                    % Handle response
                    switch answer
                        case 'Yes'
                            message = strjoin({'loading csv file:', csvfiles_table.name},' ');
                            load_csv_file = 1;

                        case 'No'
                            message = "file might be replaced (deleted)";
                            tmp_msbox = msgbox(tmp_message);
                            load_csv_file = 0;
                            waitfor(tmp_msbox);
                    end
                else
                    message = 'no csv data file found';
                end
            end

            update_board(app,message)

            %% organize required variables
            % update the file name and table descriptors
            [app] = update_file_format(app);

            % create the table
            n_image_files = app.app_data.n_image_files;
            stats_table_variable_names = app.app_data.defaults.stats_table_variable_names;
            descriptors_code = app.app_data.descriptors_code;

            % sort variable names by adding the user names
            stats_table_variable_names_complete = [descriptors_code,stats_table_variable_names];
            app.app_data.stats_table_variable_names_complete = stats_table_variable_names_complete;

            % sort variable types by adding the user names
            descriptors_variable_types = repelem("string",length(descriptors_code));
            stats_table_string_variable_complete = [descriptors_variable_types,app.app_data.defaults.stats_table_string_variable];

            stats_table_string_variable_string_variable_complete = find(strcmpi(stats_table_string_variable_complete,"string"));
            %  stats_table_double_variable = find(strcmpi(stats_table_variable_types,"double"));


            if load_csv_file
                %% a CSV file is found
                %  load(matfiles.name)
                % loaded_table = readtable(csvfiles_table.name,"Delimiter",',');
                % stats_table = loaded_table;

                % organize the table according to file order
                % Index_to_place = find(matches(app.app_data.image_files_order_table.file_name,stats_table.FileName));
                % Index_to_take = find(~cellfun(@isempty, stats_table.FileName));
                %N(Index_to_place,1:size(N,2)) = table2cell(stats_table([Index_to_take],[1:width(stats_table)]));
                % fixed during debugging
                % N(Index_to_place,1:size(N,2)) = table2cell(stats_table([Index_to_place],[1:width(stats_table)]));
                %
                % T = cell2table(N);
                % T.Properties.VariableNames = stats_table_variable_names;
                % T = convertvars(T,stats_table_string_variable,"string");
                % T = convertvars(T,stats_table_double_variable,"double");
                % stats_table = T;
                % load additional variables to the working space
                % vars_to_load = {'loaded_images_BWfinal',...
                %     'loaded_images_BWoutline',...
                %     'loaded_images_cropped',...
                %     %   'loaded_images_cropped_filtered',...
                %     %   'loaded_images_cropped_gray',...
                %     'loaded_images_rectout',...
                %     'loaded_images_refx',...
                %     'loaded_images_refy'
                %     };
                %
                % tmp_load = load(matfiles_table.name,vars_to_load{:});
                % loaded_images_BWfinal = tmp_load.loaded_images_BWfinal;
                % loaded_images_BWoutline = tmp_load.loaded_images_BWoutline;
                % loaded_images_cropped = tmp_load.loaded_images_cropped;
                % %  loaded_images_cropped_filtered = tmp_load.loaded_images_cropped_filtered;
                % %  loaded_images_cropped_gray = tmp_load.loaded_images_cropped_gray;
                % loaded_images_rectout = tmp_load.loaded_images_rectout;
                % loaded_images_refx = tmp_load.loaded_images_refx;
                % loaded_images_refy = tmp_load.loaded_images_refy;

                %%
                % load all variables and stats_table
                vars_to_load = {'app_data'};
                file_to_load = fullfile(stats_dir_path,matfiles_table.name);
                tmp_load = load(file_to_load,vars_to_load{:});
                %               tmp_load = load(matfiles_table.name,vars_to_load{:});

                app.app_data = tmp_load.app_data;

                if app.app_data.scale_known_ind
                    app.ImagescaleEditField.Value =  app.app_data.scale_pix_2_cm;
                    app.scaleisSwitch.Value = 'known';
                else
                    app.scaleisSwitch.Value = 'unknown';
                end

                app.ImageListUITable.Data = app.app_data.image_files_order_table;
                [app] = update_UI_table_color(app);

                csv_file_loaded = 1;
                %% no CSV file was found
            else
                % these variables will be used to save the images and the image processing
                % results

                loaded_images_cell = cell(length(n_image_files),1);
                app.app_data.loaded_images_cell = loaded_images_cell;

                loaded_images_cropped_cell = cell(length(n_image_files),1);
                app.app_data.loaded_images_cropped_cell = loaded_images_cropped_cell;

                loaded_images_BWfinal_cell = cell(length(n_image_files),1);
                app.app_data.loaded_images_BWfinal_cell = loaded_images_BWfinal_cell;

                loaded_images_BWoutline_cell = cell(length(n_image_files),1);
                app.app_data.loaded_images_BWoutline_cell = loaded_images_BWoutline_cell;

                loaded_images_rectout_cell = cell(length(n_image_files),1);
                app.app_data.loaded_images_rectout_cell = loaded_images_rectout_cell;

                loaded_images_refx_cell = cell(length(n_image_files),1);
                app.app_data.loaded_images_refx_cell = loaded_images_refx_cell;
                app.app_data.loaded_images_refx_cell = loaded_images_refx_cell;

                %% preallocate a table to save the morphometric features
                N = num2cell(NaN([n_image_files,length(stats_table_variable_names_complete)]));
                T = cell2table(N);
                T.Properties.VariableNames = stats_table_variable_names_complete;
                T = convertvars(T, stats_table_string_variable_string_variable_complete,"string");
                T = standardizeMissing(T, NaN);
                % T.Properties.VariableTypes = stats_table_variable_types;
                stats_table = T;
                app.app_data.stats_table = stats_table;

                %% preallocate to save EFD results
                % get the number of harmonics to be exported from the
                % defaults var
                num_harmonics = app.app_data.defaults.num_harmonics_to_export;

                % 2. Create formatted column names: a01..a08, b01..b08, c01..c08, d01..d08
                EFD_var_names = {};
                coeff_labels = {'a', 'b', 'c', 'd'};
                n_vars_efd = length(coeff_labels);

                for c = 1:length(coeff_labels)
                    for h = 1:num_harmonics
                        EFD_var_names{end+1} = sprintf('%s%02d', coeff_labels{c}, h);
                    end
                end

                n_EFD_vars_per_image = num_harmonics*n_vars_efd;
                %N = num2cell(NaN([n_image_files,n_EFD_vars_per_image]));
                %T2 = cell2table(N);

                % create the empty table to be filled
                T2 = table('Size', [n_image_files, n_EFD_vars_per_image], ...
                    'VariableTypes', repmat("double", 1, n_EFD_vars_per_image));

                T2 = standardizeMissing(T2, 0);

                T2.Properties.VariableNames = EFD_var_names;
                %T = convertvars(T, stats_table_string_variable_string_variable_complete,"string");
                % T.Properties.VariableTypes = stats_table_variable_types;
                efd_norm_table = T2;
                app.app_data.efd_norm_table = efd_norm_table;


                %%
                sample_name = app.app_data.sample_name;
                % if no csv file was found
                app.app_data.stats_table_filename = strcat('pit_analyzer_stats_results_',sample_name,'_',string(datetime("today")),'.csv');
                app.app_data.stats_matfile_filename = strcat('pit_analyzer_stats_results_',sample_name,'_',string(datetime("today")),'.mat');


                app.app_data.current_image_analyzed_ind = 1;

                csv_file_loaded = 0;
            end

            % update required fields
            app.SamplenameEditField.Value =  app.app_data.sample_name;
            app.fileformatEditField.Value =  app.app_data.file_name_format;

            % save required variable
            app.app_data.csv_file_loaded =  csv_file_loaded;

        end

        function [app] = create_folder(app)
            % update_board(app,'stage0')
            user_dir_path = app.app_data.user_dir_path;
            sample_name = app.app_data.sample_name;

            % make dir to save results
            stats_dir_name = strcat('pit_analyzer_analyses_results_',sample_name);
            app.app_data.stats_dir_name = stats_dir_name;

            stats_dir_path = fullfile(user_dir_path,stats_dir_name);
            app.app_data.stats_dir_path = stats_dir_path;

            % if iscell(stats_dir_name)
            %     stats_dir_name = stats_dir_name{1};
            % end

            % update_board(app,'stage1')

            % add folder to path
            %            addpath(genpath(fullfile(app.app_data.user_dir_path)));
            %
            % addpath(fullfile(pwd,app.app_data.approved_status));
            % % change folder
            % cd(app.app_data.user_dir_path);

            pause(1)
            if (~exist(app.app_data.stats_dir_path, 'dir')); mkdir(app.app_data.stats_dir_path); end

            % update_board(app,'stage2')

            % add subfolder to path
            % addpath(fullfile(pwd,stats_dir_name));

            %            addpath(genpath(fullfile(stats_dir_name)));
            % change folder to sub folder for saving files etc.
            cd(app.app_data.stats_dir_path);

            update_board(app,stats_dir_path)
            update_board(app,pwd())


            % results table name
            stats_table_filename = strcat('pit_analyzer_stats_results_',sample_name,'_',string(datetime("today")),'.csv');
            stats_matfile_filename = strcat('pit_analyzer_stats_results_',sample_name,'_',string(datetime("today")),'.mat');

            app.app_data.stats_table_filename = stats_table_filename;
            app.app_data.stats_matfile_filename = stats_matfile_filename;

            % update_board(app,'stage3')

        end

        function [app] = analyze_images(app)

            % if a csv file was loaded, add 1 to counter
            if app.app_data.csv_file_loaded
                first_kk = app.app_data.current_image_analyzed_ind+1;
                % app.app_data.image_files_order_table.file_name{first_kk};
            else
                first_kk = app.app_data.current_image_analyzed_ind;
            end

            message = 'index established';
            update_board(app, message)


            message = 'initalizing analysis';
            update_board(app, message)

            for kk = first_kk:app.app_data.n_image_files

                close all

                % analyze each image separately
                current_image_analyzed_ind = kk;
                app.app_data.current_image_analyzed_ind = current_image_analyzed_ind;

                currentfilename = app.app_data.image_files_order_table.file_name{kk};
                user_dir_path = app.app_data.user_dir_path;

                currentimage = imread(fullfile(user_dir_path,currentfilename));
                app.app_data.currentimage = currentimage;
                app.app_data.currentfilename = currentfilename;

                [app] = Pit_analyzer_analyze_single_image(app);

            end

            % notify the user about completion
            message = 'all images analyzed succesfully';
            update_board(app, message)

            msgbox(message)


        end

        function [app] = Pit_analyzer_analyze_single_image(app)

            % if no scale was manually added
            if ~app.app_data.scale_known_ind
                % get the image scale
                [app] = Pit_analyzer_scale_image(app);

                message = 'scale established';
                update_board(app, message)
            end

            % crop the image
            [app] = Pit_analyzer_crop_image(app);
            message = 'image cropped';
            update_board(app, message)

            % file name

            [app] = Pit_analyzer_get_file_name(app);
            %% get the file name
            currentfilename = app.app_data.currentfilename;

            % remove the format
            % currentfilename_format_ind = strfind(currentfilename,strcat('.',app.app_data.image_file_format));
            % update 31-Aug-2025
            currentfilename_format_ind = regexpi(currentfilename,strcat('.',app.app_data.image_file_format));
            currentfilename_format_ind = currentfilename_format_ind{1};

            currentfilename_format_no_format = currentfilename(1:(currentfilename_format_ind-1));
            currentfilename_format_no_format = char(currentfilename_format_no_format);

            app.app_data.currentfilename_format_no_format = currentfilename_format_no_format;


            % segmentation
            [app] = Pit_analyzer_segment_image(app);
            message = 'image segmented';
            update_board(app, message)

            % save figures
            [app] = Pit_analyzer_save_analysis_images(app);
            message = 'images saved';
            update_board(app, message)

            [app] = Pit_analyzer_save_get_user_approval(app);
            message = 'user responded';
            update_board(app, message)

            [app] = Pit_analyzer_save_data_to_vars(app);
            message = 'variables saved';
            update_board(app, message)

            [app] = Pit_analyzer_morphometric_analyses(app);
            message = 'morphometric parameters collected';
            update_board(app, message)

            [app] = Pit_analyzer_calculate_EFT(app);
            message = 'morphometric parameters collected';
            update_board(app, message)

            % update the UI table and color
            app.ImageListUITable.Data.is_analyzed{app.app_data.current_image_analyzed_ind} = 1;
            app.app_data.image_files_order_table = app.ImageListUITable.Data;

            [app] = update_UI_table_color(app);
            message = 'UI table updated';
            update_board(app, message)

            [app] = Pit_analyzer_save_data_to_files(app);
            message = 'data saved to files';
            update_board(app, message)

            message= strjoin({'image:',app.app_data.currentfilename,'analyzed'},' ');
            update_board(app,message);

        end

        function [app] = Pit_analyzer_scale_image(app)

            %  kk = app.app_data.current_image_analyzed_ind;

            currentfilename = app.app_data.currentfilename;
            currentimage = app.app_data.currentimage;

            % scale the image
            tmp_title = currentfilename(1:strfind(currentfilename,'.')-1);
            scale_pts = PitAnalyzer_read_points(currentimage, 2, tmp_title);
            scale_pix_2_cm = pdist(scale_pts','euclidean');
            app.app_data.tmp_title = tmp_title;

            % save the scale into the structure
            app.app_data.scale_pix_2_cm = scale_pix_2_cm;
            app.ImagescaleEditField.Value = scale_pix_2_cm;

            close all
            message=strcat('image scale established:', num2str(round(scale_pix_2_cm)));
            update_board(app,message);

        end

        function [app] = Pit_analyzer_crop_image(app)

            % crop the image
            %% crop the image
            % figure;
            % tmp_text = 'mark area and crop using right click';
            % text(0.1,0.5,tmp_text)
            % waitforbuttonpress()

            message = 'mark area and crop using right click';
            update_board(app,message);

            uiwait(msgbox(message,"crop image","modal"))
            %%
            currentimage = app.app_data.currentimage;

            [cropped_image,loaded_images_rectout] = imcrop(currentimage);
            loaded_images_refx = [loaded_images_rectout(1),loaded_images_rectout(1)+loaded_images_rectout(3)];
            loaded_images_refy = [loaded_images_rectout(2),loaded_images_rectout(2)+loaded_images_rectout(4)];

            close all

            app.app_data.cropped_image = cropped_image;
            app.app_data.loaded_images_rectout = loaded_images_rectout;
            app.app_data.loaded_images_refx = loaded_images_refx;
            app.app_data.loaded_images_refy = loaded_images_refy;

        end

        function [app] = Pit_analyzer_segment_image(app)
            % message = 'stage0';
            % update_board(app,message)
            %% segment the image
            % current_image_analyzed_ind = app.app_data.current_image_analyzed_ind;
            cropped_image = app.app_data.cropped_image;
            I = cropped_image;

            % message = 'stage1';
            % update_board(app,message)


            % filter S&P noise
            for kk=1:size(I,3)
                I(:,:,kk) = medfilt2(I(:,:,kk),[3,3]);
            end


            % message = 'stage2';
            % update_board(app,message)

            %% Gabor filter
            % this comes from the MATLAB example
            % https://www.mathworks.com/help/images/ref/imsegkmeans.html#mw_2085e245-1f8c-462a-afb4-41b0bef725a9
            % RGB = cropped_image;
            % I = im2gray(im2single(RGB));
            %
            % wavelength = 2.^(0:5) * 3;
            % orientation = 0:45:135;
            % g = gabor(wavelength,orientation);
            %
            % gabormag = imgaborfilt(I,g);
            %
            % for i = 1:length(g)
            %     sigma = 0.5*g(i).Wavelength;
            %     gabormag(:,:,i) = imgaussfilt(gabormag(:,:,i),3*sigma);
            % end
            %
            % nrows = size(RGB,1);
            % ncols = size(RGB,2);
            % [X,Y] = meshgrid(1:ncols,1:nrows);
            %
            % featureSet = cat(3,I,gabormag,X,Y);
            % L2 = imsegkmeans(featureSet,2,"NormalizeInput",true);
            % C = labeloverlay(I,L2);
            % figure;
            % imshow(C)
            % title("Gabor filter")

            %% Kmeans segmentation
            % segment the image using kmeans algorithm
            % L is the segmented image
            [Labels_segments,~] = imsegkmeans(cropped_image,2);
            Labels_segments_single = double(Labels_segments);

            C = labeloverlay(I,Labels_segments);
            figure; imshow(C);
            % message = 'stage3';
            % update_board(app,message)

            %% automated identification of pit
            % get a central rectangle
            size_of_I = size(I,[1,2]);

            center_of_I = floor(size_of_I./2);

            rectangle_x_coord = [center_of_I(2)-10, center_of_I(2)+10];
            rectangle_y_coord = [center_of_I(1)-10, center_of_I(1)+10];

            center_rectangle_of_I_horz = [min(rectangle_x_coord):max(rectangle_x_coord)];

            center_rectangle_of_I_vert = [min(rectangle_y_coord):max(rectangle_y_coord)];
            center_rectangle = Labels_segments_single(center_rectangle_of_I_vert,center_rectangle_of_I_horz);

            likely_pit_label = mode(center_rectangle(:));

            message = 'stage4';
            update_board(app,message)

            %% get pit label
            pit_label = likely_pit_label;
            unique_labels = unique(Labels_segments_single);
            non_pit_label = unique_labels([ismember(unique_labels,pit_label)]);

            message = 'stage5';
            update_board(app,message)

            close all
            %% image processing
            binarized_image = im2double(cropped_image(:,:,1));
            binarized_image(Labels_segments_single==pit_label) = 1;
            binarized_image(Labels_segments_single~=pit_label) = 0;

            dilation_factor = 5;
            se90 = strel('disk',dilation_factor);
            se0 = strel('disk',dilation_factor);

            BWsdil = imdilate(binarized_image,[se90 se0]);
            BWdfill = imfill(BWsdil,'holes');
            BWdfill = medfilt2(BWdfill,[10,10]);
            BWdfill = imfill(BWdfill,'holes');
            BWdfill = medfilt2(BWdfill,[20,20]);

            BWnobord = imclearborder(BWdfill,8);
            %imshow(BWfinal)

            erosion_factor = 8;
            % erode excess pixels
            % erosion_factor = 2;
            seD = strel('disk',[erosion_factor]);
            BWfinal = imerode(BWnobord,seD);
            %imshow(BWfinal)
            BWfinal = imfill(BWfinal,'holes');
            BWfinal = medfilt2(BWfinal,[6,6]);

            BWoutline = bwperim(BWfinal);

            message = 'stage6';
            update_board(app,message)

            %% display and save segmentation results for reference
            % add the ouline as green line
            rgbImage = cropped_image;
            rgbImage_green = rgbImage(:,:,2);
            rgbImage_green(BWoutline) = 255;
            rgbImage(:,:,2) = rgbImage_green;

            % add the ouline as green line
            loaded_images_cropped_gray = im2gray(cropped_image);
            Segout = loaded_images_cropped_gray;
            Segout(BWoutline) = 255;
            grayImage = cat(3, loaded_images_cropped_gray, Segout, loaded_images_cropped_gray);

            image_perimeter = BWoutline;
            image_perimeter(size(image_perimeter)) = 0;
            image_perimeter(BWoutline) = 255;

            tmp_perimeter = imbinarize(im2double(BWoutline),"adaptive","Sensitivity",0.4);
            tmp_perimeter = abs(tmp_perimeter-1);
            tmp_perimeter = uint8(255 * tmp_perimeter);

            images_cell_for_display = {rgbImage,grayImage,BWfinal,tmp_perimeter};
            app.app_data.images_cell_for_display = images_cell_for_display;

            app.app_data.tmp_perimeter = tmp_perimeter;

            %% Align the image of the sillhoute for EFT analyses
            [app] = Pit_analyzer_align_grain(app);
            BWoutline_aligned = app.app_data.BWoutline_aligned;

            additional_images = {
                grayImage,...
                BWfinal,...
                tmp_perimeter,...
                BWoutline_aligned};

            app.app_data.additional_images = additional_images;

            message = 'stage7';
            update_board(app,message)

            close all
        end

        function [app] = Pit_analyzer_save_analysis_images(app)
            %% notify user
            message = 'saving images, please wait';
            msgbox(message);
            update_board(app,message);

            %% set the required variables for presentation and export
            images_cell_for_display = app.app_data.images_cell_for_display;
            additional_images = app.app_data.additional_images;
            app.app_data.current_image_analyzed_ind
            cropped_image = app.app_data.cropped_image;
            currentimage = app.app_data.currentimage;
            stats_dir_path = app.app_data.stats_dir_path;

            update_board(app,stats_dir_path)
            %% export the montage
            montage_figure = figure('units','normalized','outerposition',[0 0 1 1]);
            montage_ax = gca;
            montage(images_cell_for_display,"Size",[1,4],"Parent",montage_ax);

            pause(0.5)
            filename_for_saving = app.app_data.currentfilename_format_no_format;

            tmp_title = filename_for_saving;
            title(strjoin({tmp_title,'|','Segmentation results'},' '),'Interpreter','none');
            tmp_file_name = strcat(strjoin({tmp_title,'_','cropped_segmentation results'},' '),'.png');
            tmp_file_name = fullfile(stats_dir_path, tmp_file_name);
            update_board(app,tmp_file_name)

            exportgraphics(gcf(),tmp_file_name,"Resolution", app.app_data.defaults.dpi.dpi_for_saving_small_figs,'Padding', 'figure')

            %% export additional images
            pause(0.5)
            BWfinal = additional_images{2};
            figure;imshow(BWfinal)
            %     saveas(tmp_figure,strcat(strjoin({tmp_title,'_','cropped_BWfinal'},' '),'.png'))
            tmp_file_name = strcat(strjoin({tmp_title,'_','cropped_BWfinal'},''),'.png');
            tmp_file_name = fullfile(stats_dir_path, tmp_file_name);
            update_board(app,tmp_file_name)
            %  saveas(gcf(),tmp_file_name)
            %  exportgraphics(gcf(),tmp_file_name,"Resolution",dpi_for_saving_small_figs, 'Padding', 'figure')
            imwrite(BWfinal, tmp_file_name,'XResolution',size(BWfinal,1)*2,'YResolution',size(BWfinal,2)*2);

            %%
            pause(0.5)

            BWoutline = additional_images{3};
            figure;imshow(BWoutline)
            %     saveas(tmp_figure,strcat(strjoin({tmp_title,'_','cropped_BWfinal'},' '),'.png'))
            tmp_file_name = strcat(strjoin({tmp_title,'_','cropped_BWoutline'},''),'.png');
            tmp_file_name = fullfile(stats_dir_path, tmp_file_name);
            update_board(app,tmp_file_name)
            %  saveas(gcf(),tmp_file_name)
            % exportgraphics(gcf(),tmp_file_name,"Resolution",dpi_for_saving_small_figs, 'Padding', 'figure')
            imwrite(BWoutline, tmp_file_name,'XResolution',size(BWoutline,1)*2,'YResolution',size(BWoutline,2)*2);


            %%
            pause(0.5)

            figure;imshow(cropped_image)
            %     saveas(tmp_figure,strcat(strjoin({tmp_title,'_','cropped_BWfinal'},' '),'.png'))
            tmp_file_name = strcat(strjoin({tmp_title,'_','cropped_RGB'},''),'.png');
            tmp_file_name = fullfile(stats_dir_path, tmp_file_name);
            update_board(app,tmp_file_name)
            %  saveas(gcf(),tmp_file_name)
            %  exportgraphics(gcf(),tmp_file_name,"Resolution",dpi_for_saving_small_figs, 'Padding', 'figure')
            imwrite(cropped_image, tmp_file_name,'XResolution',size(cropped_image,1)*2,'YResolution',size(cropped_image,2)*2);

            %% notify user
            message = 'saving images, please remain patient';
            update_board(app,message);
            %% classified image by pixel for future machine learning applications
            % export original image
            loaded_images_refx = app.app_data.loaded_images_refx;
            loaded_images_refy = app.app_data.loaded_images_refy;

            tmp_file_name = strcat(strjoin({tmp_title,'_','complete_original'},''),'.png');
            tmp_file_name = fullfile(stats_dir_path, tmp_file_name);
            update_board(app,tmp_file_name)

            imwrite(currentimage, tmp_file_name,'XResolution',size(currentimage,1),'YResolution',size(currentimage,2));

            % create a labelled masked image for future ML implementation of segmentation neural networks
            labelled_image = zeros(size(currentimage(:,:,1)));

            x_corrd = round([min(loaded_images_refx):max(loaded_images_refx)]);
            y_ccord = round([min(loaded_images_refy):max(loaded_images_refy)]);

            labelled_image(y_ccord,x_corrd) = double(BWfinal);

            tmp_file_name = strcat(strjoin({tmp_title,'_','complete_BWfinal'},''),'.png');
            tmp_file_name = fullfile(stats_dir_path, tmp_file_name);
            update_board(app,tmp_file_name)

            imwrite(labelled_image, tmp_file_name,'XResolution',size(currentimage,1),'YResolution',size(currentimage,2));

            %%
            BWoutline_aligned = additional_images{4};

            figure;imshow(BWoutline_aligned)
            %     saveas(tmp_figure,strcat(strjoin({tmp_title,'_','cropped_BWfinal'},' '),'.png'))
            tmp_file_name = strcat(strjoin({tmp_title,'_','cropped_BWoutline_aligned'},''),'.png');
            tmp_file_name = fullfile(stats_dir_path, tmp_file_name);
            update_board(app,tmp_file_name)
            %  saveas(gcf(),tmp_file_name)
            % exportgraphics(gcf(),tmp_file_name,"Resolution",dpi_for_saving_small_figs, 'Padding', 'figure')
            imwrite(BWoutline_aligned, tmp_file_name,'XResolution',size(BWoutline_aligned,1)*2,'YResolution',size(BWoutline_aligned,2)*2);

            %%
            close all

            message = 'images saved';
            update_board(app,message);

        end

        function [app] = Pit_analyzer_morphometric_analyses(app)
            %% get statistical properties
            stats_table2 = app.app_data.stats_table;
            current_image_analyzed_ind = app.app_data.current_image_analyzed_ind;

            current_file_name = app.app_data.image_files_list_cell{current_image_analyzed_ind};
            % BWfinal = app.app_data.additional_images{2};
            BWfinal = app.app_data.loaded_images_BWfinal_cell{current_image_analyzed_ind};

            currentfilename_format_no_format = app.app_data.currentfilename_format_no_format;
            currentfilename = app.app_data.currentfilename;

            %%
            % plug in the image details using the descriptors provided by
            % the user

            % get the specific descriptors content of this file
            underscore_ind = strfind(currentfilename_format_no_format,'_');
            underscore_ind = [0,underscore_ind,(length(currentfilename_format_no_format)+1)];

            try
                descriptors_indices = cell(1,(length(underscore_ind)-1));
                for kk = 1:(length(underscore_ind)-1)
                    descriptors_indices{kk} = ((underscore_ind(kk)+1):(underscore_ind(kk+1)-1));
                end

                for kk = 1:(length(descriptors_indices))
                    % add 1 to the column index because the first column is the
                    % FileName
                    stats_table2(current_image_analyzed_ind,1+kk) = {currentfilename_format_no_format(descriptors_indices{kk})};
                end
            catch

                message = "error with getting file name descriptors. make file format sure format matches file structure.";
                errdlg(message)
                update_board(app,message)

            end
            % plug in additional information
            stats_table2.FileName(current_image_analyzed_ind) = {current_file_name};
            stats_table2.FileName(current_image_analyzed_ind) = {currentfilename};

            stats_table2.PixelsToCm(current_image_analyzed_ind) = app.app_data.scale_pix_2_cm;
            stats_table2.Approved(current_image_analyzed_ind) = app.app_data.approved_status;

            % % continue here - place the values from the image name into the
            % % table
            % stats_table2.StoneName(current_image_index) = str2double(tmp_StoneName);
            % stats_table2.TreeCode(current_image_index) = {tmp_TreeCode};
            % stats_table2.StoneView(current_image_index) = {tmp_VL_or_VD};
            % stats_table2.Cultivar(current_image_index) = {cultivar_name};
            % stats_table2.Approved(current_image_index) =  is_image_approved;

            tmp_stats = regionprops(BWfinal,"all");
            tmp_stats_table = struct2table(tmp_stats,'AsArray',true);
            [~, max_object_row] = max(tmp_stats_table.Area);
            rows_to_remove = setdiff(1:size(tmp_stats,1),max_object_row);

            % if more than one object is found, keep only the biggest one
            if ~isempty(rows_to_remove)
                try
                    tmp_stats(rows_to_remove) = [];
                catch
                    warning('could not remove additional rows from structure')
                end

            end

            % get the feret properties to keep the orthogonal axis
            % https://blogs.mathworks.com/steve/2018/04/17/feret-properties-wrapping-up/?s_tid=srchtitle_site_search_2_antipodalPairs
            tmp_feret_prop = PitAnalyzer_feretProperties(tmp_stats);

            % more than one object was identified (e.g., dust)
            if height(tmp_stats_table) > 1
                tmp_stats_table = tmp_stats_table(tmp_stats_table.Area == max(tmp_stats_table.Area),:);
            end

            % check how many objects were detected
            if height(tmp_stats_table) > 1
                message = 'something went wrong (more than a single object detected)';
                warndlg(message)

            elseif height(tmp_stats_table) < 1
                message = 'something went wrong (no object detected)';
                warndlg(message)

            elseif height(tmp_stats_table) == 1
                message = 'a single object detected';
            end

            update_board(app,message);

            stats_table2.Area(current_image_analyzed_ind) = tmp_stats_table.Area;
            stats_table2.Centroid_x(current_image_analyzed_ind) = tmp_stats_table.Centroid(1);
            stats_table2.Centroid_y(current_image_analyzed_ind) = tmp_stats_table.Centroid(2);
            stats_table2.Circularity(current_image_analyzed_ind) = tmp_stats_table.Circularity;
            stats_table2.ConvexArea(current_image_analyzed_ind) = tmp_stats_table.ConvexArea;
            stats_table2.Eccentricity(current_image_analyzed_ind) = tmp_stats_table.Eccentricity;
            stats_table2.EquivDiameter(current_image_analyzed_ind) = tmp_stats_table.EquivDiameter;
            stats_table2.MajorAxisLength(current_image_analyzed_ind) = tmp_stats_table.MajorAxisLength;
            stats_table2.MaxFeretDiameter(current_image_analyzed_ind) = tmp_stats_table.MaxFeretDiameter;
            stats_table2.MinorAxisLength(current_image_analyzed_ind) = tmp_stats_table.MinorAxisLength;
            stats_table2.MinFeretDiameter(current_image_analyzed_ind) = tmp_stats_table.MinFeretDiameter;
            stats_table2.OrthogonalDiameter(current_image_analyzed_ind) = tmp_feret_prop.OrthogonalDiameter;
            stats_table2.MaxFeretDiameterControl(current_image_analyzed_ind) = tmp_feret_prop.MaxFeretDiameter;
            stats_table2.Perimeter(current_image_analyzed_ind) = tmp_stats_table.Perimeter;
            stats_table2.Solidity(current_image_analyzed_ind) = tmp_stats_table.Solidity;

            % these stats are from the Zircon paper
            stats_table2.FormFactor(current_image_analyzed_ind) = (pi()*4*stats_table2.Area(current_image_analyzed_ind))./(stats_table2.Perimeter(current_image_analyzed_ind).^2);
            stats_table2.Roundness(current_image_analyzed_ind) = (4*stats_table2.Area(current_image_analyzed_ind))./(pi().*stats_table2.MajorAxisLength(current_image_analyzed_ind).^2);
            stats_table2.Compactness(current_image_analyzed_ind) = sqrt(4./pi()*stats_table2.Area(current_image_analyzed_ind))./(stats_table2.MajorAxisLength(current_image_analyzed_ind));

            stats_table2.AspectRatio(current_image_analyzed_ind) = (stats_table2.MaxFeretDiameter(current_image_analyzed_ind))./(stats_table2.OrthogonalDiameter(current_image_analyzed_ind));

            % save the data table to the app_data struct
            app.app_data.stats_table = stats_table2;
            message = strjoin({'file',currentfilename,'segmented succesfully'},' ');
            update_board(app,message);

        end

        function [app] = Pit_analyzer_save_data_to_vars(app)
            %% save images into variables
            current_image_analyzed_ind = app.app_data.current_image_analyzed_ind;

            app.app_data.loaded_images_cropped_cell{current_image_analyzed_ind} = app.app_data.cropped_image;

            BWfinal = app.app_data.additional_images{2};
            app.app_data.loaded_images_BWfinal_cell{current_image_analyzed_ind} = BWfinal;

            BWoutline = app.app_data.images_cell_for_display{4};
            app.app_data.loaded_images_BWoutline_cell{current_image_analyzed_ind} = BWoutline;

            BWoutline_aligned = app.app_data.BWoutline_aligned;
            app.app_data.loaded_images_BWoutline_aligned_cell{current_image_analyzed_ind} = BWoutline_aligned;

            app.app_data.loaded_images_rectout_cell{current_image_analyzed_ind} = app.app_data.loaded_images_rectout;

            app.app_data.loaded_images_refx_cell{current_image_analyzed_ind} = app.app_data.loaded_images_refx;
            app.app_data.loaded_images_refx_cell{current_image_analyzed_ind} = app.app_data.loaded_images_refy;

        end

        function [app] = Pit_analyzer_save_data_to_files(app)
            stats_dir_path = app.app_data.stats_dir_path;

            stats_table2 = app.app_data.stats_table;
            stats_table_filename = app.app_data.stats_table_filename;

            stats_matfile_filename = app.app_data.stats_matfile_filename;

            % save the csv table
            tmp_file_name = fullfile(stats_dir_path, stats_table_filename);
            update_board(app,tmp_file_name);

            efd_norm_table = app.app_data.efd_norm_table;
            stats_table2_with_efd = [stats_table2, efd_norm_table];
            app.app_data.stats_table2_with_efd = stats_table2_with_efd;

            %writetable(stats_table2,tmp_file_name)%,'WriteMode','append')
            writetable(stats_table2_with_efd,tmp_file_name)%,'WriteMode','append')

            % limit the saving of the matfile to non-figures
            %  varData = whos;
            %  saveIndex = cellfun(@isempty, regexp({varData.class}, 'matlab.(graphics|ui)'));
            %  saveVars = {varData(saveIndex).name};
            %  save(stats_matfile_filename, saveVars{:});

            app_data = app.app_data;
            tmp_file_name = fullfile(stats_dir_path, stats_matfile_filename);
            update_board(app,tmp_file_name);

            save(tmp_file_name, 'app_data');

            disp(strjoin({'files saved'},' '))


        end

        function [app] = Pit_analyzer_save_get_user_approval(app)

            % first clear the previous approval decision
            [app] = clear_approval_button(app);

            message = "do you approve analyses results?";
            update_board(app, message)
            uiwait(msgbox(message, "user apprval","modal"));

            set(app.notapprovedButton, "Value",0)
            set(app.approvedButton, "Value",0)
            set(app.unknownButton, "Value",1)

            % if any button is pushed, collect the information on user approval
            % pause until the user decides if the analyses is approved
            while app.unknownButton.Value

                pause(1);

                % if the user have selected an option
                if (app.notapprovedButton==1) || (app.approvedButton==1)
                    break
                end

            end

        end

        function [app] = get_scale_status(app)
            scale_known_value = app.scaleisSwitch.Value;

            % the scale is known
            switch scale_known_value
                case 'known' % the scale is known
                    set(app.ImagescaleEditField,'Editable','on')

                    % update the scale
                    % save the scale into the structure
                    scale_pix_2_cm = app.ImagescaleEditField.Value;

                    if scale_pix_2_cm==0
                        warndlg('scale is set to zero!')
                    end

                    app.app_data.scale_pix_2_cm = scale_pix_2_cm;
                    app.app_data.scale_known_value = scale_known_value;
                    app.app_data.scale_known_ind = 1;


                case 'unknown' % the scale is unknown
                    set(app.ImagescaleEditField,'Value',0)
                    set(app.ImagescaleEditField,'Editable','off')
                    app.app_data.scale_known_ind = 0;

            end
        end

        function [app] = update_file_format(app)
            file_name_format = app.app_data.file_name_format;

            message = strcat('file name format set:',file_name_format);
            update_board(app,message);

            %file_format_ind = strfind(file_name_format,strcat('.',app.app_data.image_file_format));

            % update Aug-31-2025
            % find the location of the file type in order to extract the
            % file format for the table
            file_format_ind = regexpi(file_name_format,strcat('.',app.app_data.image_file_format));
            file_format_ind = file_format_ind{1};
            file_format_ind_all = file_format_ind:length(file_name_format);

            if isempty(file_format_ind_all)
                message = 'could resolve file format. make sure a dot is marked. e.g., "olive_tree.png';
                update_board(app,message);
                errordlg(message,'error resolving file name format')
            else

            end

            % get the file name without the file type
            file_format_no_format = app.app_data.file_name_format(1:(file_format_ind-1));

            % find the underscores to fix the table column titles
            underscore_ind = strfind(file_format_no_format,'_');
            underscore_ind = [0,underscore_ind,(length(file_format_no_format)+1)];

            descriptors_code = cell(1,(length(underscore_ind)-1));

            % find out all the descriptor codes used to identify each photo
            for kk = 1:(length(underscore_ind)-1)
                descriptors_code{kk} = file_format_no_format((underscore_ind(kk)+1):(underscore_ind(kk+1)-1));
            end

            % create the variables for the table titles
            descriptors_code = ["FileName",descriptors_code];

            app.app_data.file_format_no_format = file_format_no_format;
            app.app_data.descriptors_code = descriptors_code;
            % app.app_data.descriptors_indices = descriptors_indices;

        end

        function [app] = clear_approval_button(app)

            % default to no selection of approval button
            set(app.notapprovedButton, "Value",0)
            set(app.approvedButton, "Value",0)
        end

        function [app] = update_UI_table_color(app)

            %% update the imageListUITable
            %styleIndices = app.ImageListUITable.Data.is_analyzed{:}==1;
            [rows_to_color,~] = find(cell2mat(table2array(app.ImageListUITable.Data(:,3))));

            s = uistyle('BackgroundColor',"#d5f5e3");
            addStyle(app.ImageListUITable,s,'row',rows_to_color);


            [rows_to_white,~] = find(cell2mat(table2array(app.ImageListUITable.Data(:,3)))==0);

            s = uistyle('BackgroundColor',"white");
            addStyle(app.ImageListUITable,s,'row',rows_to_white);
        end

        function [app] = load_csvs_in_folder(app)

            %%
            app = get_folder_type(app);

            1+1

            app = get_csv_list(app);
            %% load the csv files
            %       try
            % refresh the necessary fields
            n_csv_files = app.app_data.n_csv_files;
            csvfiles_table = app.app_data.csvfiles_table;
            data_dir_path = app.app_data.data_dir_path;

            loaded_data_tables = cell(n_csv_files,1);
            app.app_data.loaded_data_tables_cm = cell(n_csv_files,1);
            app.app_data.merged_table_cm_ordered = cell(n_csv_files,1);

            % collect necessary data
            loaded_data_tables_names = csvfiles_table.name;
            app.app_data.loaded_data_tables_names = loaded_data_tables_names;

            for ii=[1:n_csv_files]
                current_table_ind = ii;

                if iscell(loaded_data_tables_names)
                    % more than one csv should be read
                    loaded_data_tables{ii} = readtable(fullfile(data_dir_path,loaded_data_tables_names{ii}));
                else
                    % only one csv should be read
                    loaded_data_tables{ii} = readtable(fullfile(data_dir_path,loaded_data_tables_names));
                end

                app.app_data.loaded_data_tables = loaded_data_tables;
                app.app_data.loaded_data_tables_index = ii;

                [app] = convert_pixels_to_cm(app);

                message = 'table converted';
                update_board(app,message)

                % merge rows only if the user requested
                if app.app_data.merge_rows
                    % if the data contains multiple views of the same grain
                    % if sum(contains(loaded_data_tables{ii}.Properties.VariableNames,'View','IgnoreCase',true)) > 0
                    % [app] = organize_table_by_views(app);
                    [app] = organize_table_by_selected_col(app);
                    % end
                else
                    %%
                    % assuming all columns before the file name are the file
                    % identity descriptors

                end

                1+1
                merged_table = vertcat(app.app_data.loaded_data_tables_cm{current_table_ind})

                % find the file name
                file_name_cols = find(contains(merged_table.Properties.VariableNames,'Name','IgnoreCase',true));

                % find the first data col named area
                area_col = min(find(contains(merged_table.Properties.VariableNames,'Area','IgnoreCase',true)));

                % all_cols_ind = [(min(file_name_cols)):width(merged_table)];
                all_cols_ind = [1:width(merged_table)];
                data_cols = [area_col:width(merged_table)];
                meta_data_cols = [1:(area_col-1)];

                app.app_data.meta_data_cols = meta_data_cols;
                app.app_data.meta_data_cell = merged_table.Properties.VariableNames(meta_data_cols);

                remaining_meta_data_cols_ind = meta_data_cols(~ismember([meta_data_cols],file_name_cols));

                remaining_cols_ind = all_cols_ind(~ismember([all_cols_ind],file_name_cols));

                % reorder columns
                % merged_table_cm_ordered = merged_table(:,[[1:(min(file_name_cols)-1)],file_name_cols,remaining_cols_ind]);
                merged_table_cm_ordered = merged_table(:,[remaining_meta_data_cols_ind,file_name_cols,data_cols]);

                % save into variable
                app.app_data.merged_table_cm_ordered{current_table_ind} = merged_table_cm_ordered;

            end


            % load the references data
            %        app.app_data.loaded_data_tables_cm = [];
            %        app.app_data.merged_table_cm_ordered = [];
            folder_type = app.app_data.folder_type;

            %%

            1+1

            if strcmp(folder_type,'references_folder')
                message  = 'references data loaded and organized';
                app.app_data.loaded_data_tables_cm_references = app.app_data.loaded_data_tables_cm;
                app.app_data.loaded_data_tables_cm_ordered_references = app.app_data.merged_table_cm_ordered;
                app.app_data.loaded_data_cm_ordered_references_all = vertcat(app.app_data.loaded_data_tables_cm_ordered_references{:});

                % load the targets data
            elseif strcmp(folder_type,'targets_folder')
                message  = 'targets data loaded and organized';
                app.app_data.loaded_data_tables_cm_targets = app.app_data.loaded_data_tables_cm;
                app.app_data.loaded_data_tables_cm_ordered_targets = app.app_data.merged_table_cm_ordered;
                app.app_data.loaded_data_cm_ordered_targets_all = vertcat(app.app_data.loaded_data_tables_cm_ordered_targets{:});
            else
                message = 'no data folders were set';
            end
            message
            update_board(app,message)
            % catch
            %     message = 'cannot join tables, make sure columns have the same titles';
            %     update_board(app,message);
            %     msgbox(message,"Error","error");
            %     error(message)
            % end
        end

        function [app] = convert_pixels_to_cm(app)

            ii = app.app_data.loaded_data_tables_index;
            current_table = app.app_data.loaded_data_tables{ii};
            current_table_cm = current_table;

            median_conversion_factor = median(current_table.PixelsToCm);

            message = strjoin({'median conversion factor is:', num2str(median_conversion_factor)},' ');
            update_board(app,message);

            % continue here
            current_table_cm.Area = current_table.Area./(median_conversion_factor^2);
            current_table_cm.Centroid_x = current_table.Centroid_x./(median_conversion_factor);
            current_table_cm.Centroid_y = current_table.Centroid_y./(median_conversion_factor);
            current_table_cm.ConvexArea = current_table.ConvexArea./(median_conversion_factor);
            current_table_cm.EquivDiameter = current_table.EquivDiameter./(median_conversion_factor);
            current_table_cm.MajorAxisLength = current_table.MajorAxisLength./(median_conversion_factor);
            current_table_cm.MaxFeretDiameter = current_table.MaxFeretDiameter./(median_conversion_factor);
            current_table_cm.MinorAxisLength = current_table.MinorAxisLength./(median_conversion_factor);
            current_table_cm.MinFeretDiameter = current_table.MinFeretDiameter./(median_conversion_factor);
            current_table_cm.OrthogonalDiameter = current_table.OrthogonalDiameter./(median_conversion_factor);
            current_table_cm.Perimeter = current_table.Perimeter./(median_conversion_factor);

            current_table_cm(:,{'Centroid_x','Centroid_y','PixelsToCm', 'Approved'}) = [];
            app.app_data.loaded_data_tables_cm{ii} = current_table_cm;

            if iscell(app.app_data.loaded_data_tables_names)
                % more than a single file is loaded
                message = strjoin({'table:', app.app_data.loaded_data_tables_names{ii}, 'loaded succesfully and converted to cm'},' ');
            else
                % a single file is loaded
                message = strjoin({'table:', app.app_data.loaded_data_tables_names, 'loaded succesfully and converted to cm'},' ');
            end
            update_board(app,message);

        end

        function [app] = organize_table_by_views(app)
            current_table_ind = app.app_data.loaded_data_tables_index;
            current_table = app.app_data.loaded_data_tables_cm{current_table_ind};

            grain_view_data_col = find(contains(current_table.Properties.VariableNames,'View','IgnoreCase',true),1,'first');
            views_of_grain = unique(current_table{:,grain_view_data_col});

            current_table_view_indices = cell(length(views_of_grain),1);
            current_table_view_data = cell(length(views_of_grain),1);

            for ii = 1:length(views_of_grain)
                current_table_view_indices{ii} =  strcmp(current_table{:,grain_view_data_col},views_of_grain{ii});
                current_table_view_data{ii} =  current_table(current_table_view_indices{ii},:);
            end

            %
            % % get indices of VD images
            % current_table_VD_indices = contains(current_table.StoneView,'VD','IgnoreCase',true);
            % current_table_VD = current_table(current_table_VD_indices,:);
            %
            % % get indices of VL images
            % current_table_VL_indices = contains(current_table.StoneView,'VL','IgnoreCase',true);
            % current_table_VL = current_table(current_table_VL_indices,:);

            first_data_col = find(contains(current_table.Properties.VariableNames,'Area','IgnoreCase',true),1,'first');
            file_name_col = find(contains(current_table.Properties.VariableNames,'FileName','IgnoreCase',true),1,'first');

            % add the letters VD and VL to the columns with the measure
            % variables
            % current_table_VD.Properties.VariableNames([file_name_col,first_data_col:width(current_table_VD)]) = strcat(current_table_VD.Properties.VariableNames(first_data_col:width(current_table_VD)),'_VD');
            % current_table_VL.Properties.VariableNames([file_name_col,first_data_col:width(current_table_VL)]) = strcat(current_table_VL.Properties.VariableNames(first_data_col:width(current_table_VL)),'_VL');
            %
            % % reorder columns
            % current_table_VD = current_table_VD(:,[(2:first_data_col-1),file_name_col,(first_data_col:width(current_table_VD))]);
            % current_table_VL = current_table_VL(:,[(2:first_data_col-1),file_name_col,(first_data_col:width(current_table_VD))]);


            ordered_tables = cell(length(views_of_grain),1);

            for ii = 1:length(views_of_grain)

                tmp_table = current_table_view_data{ii};
                tmp_table.Properties.VariableNames([file_name_col,first_data_col:width(tmp_table)]) = strcat(tmp_table.Properties.VariableNames([file_name_col,first_data_col:width(tmp_table)]),strcat('_',views_of_grain{ii}));
                tmp_table = tmp_table(:,[(2:first_data_col-1),file_name_col,(first_data_col:width(tmp_table))]);
                %%

                % remove the grain view column
                grain_view_data_col = find(contains(tmp_table.Properties.VariableNames,'View','IgnoreCase',true),1,'first');
                tmp_table(:,grain_view_data_col) = [];

                ordered_tables{ii} = tmp_table;
            end

            % assuming only two views exist
            merged_table = ordered_tables{1};
            for ii = 2:length(views_of_grain)
                try
                    merged_table = join(merged_table, ordered_tables{ii});
                catch
                    message = 'cannot join tables, make sure columns have the same titles';
                    update_board(app,message);
                    msgbox(message,"Error","error");
                    error(message)
                    stop;
                end
            end

            % assuming all columns before the file name are the file
            % identity descriptors
            file_name_cols = find(contains(merged_table.Properties.VariableNames,'Name','IgnoreCase',true));
            all_cols_ind = [(min(file_name_cols)):width(merged_table)];
            remaining_cols_ind = all_cols_ind(~ismember([all_cols_ind],file_name_cols));

            % reorder columns
            merged_table_cm_ordered = merged_table(:,[[1:(min(file_name_cols)-1)],file_name_cols,remaining_cols_ind]);

            % save into variable
            app.app_data.merged_table_cm_ordered{current_table_ind} = merged_table_cm_ordered;

        end

        function [app] = update_list_box(app)
            folder_type = app.app_data.folder_type;

            1+1

            update_list_name = app.app_data.update_list_name;
            try
                category_column_name = app.app_data.category_column;
                category_column = find(strcmp(category_column_name,app.app_data.loaded_data_cm_ordered_references_all.Properties.VariableNames))
            catch
                category_column = 1;
            end

            if strcmp(update_list_name,'update_references')
                unique_references = unique(app.app_data.loaded_data_cm_ordered_references_all{:,category_column});
                app.ReferencesListBox.Items = unique_references;

            elseif strcmp(update_list_name,'update_targets')
                unique_targets = unique(app.app_data.loaded_data_cm_ordered_targets_all{:,category_column});
                app.TargetsListBox.Items = unique_targets;
            end

            if strcmp(update_list_name,'update_features')

                if isfield(app.app_data,'loaded_data_cm_ordered_references_all')
                    %  name_columns = find(contains(app.app_data.loaded_data_cm_ordered_references_all.Properties.VariableNames,'File'));
                    area_columns = (find(contains(app.app_data.loaded_data_cm_ordered_references_all.Properties.VariableNames,'Area'),1,"first"));

                    %    unique_features_references = app.app_data.loaded_data_cm_ordered_references_all.Properties.VariableNames((max(name_columns)+1):width(app.app_data.loaded_data_cm_ordered_references_all));
                    unique_features_references = app.app_data.loaded_data_cm_ordered_references_all.Properties.VariableNames(area_columns:width(app.app_data.loaded_data_cm_ordered_references_all));

                end

                if isfield(app.app_data,'loaded_data_cm_ordered_targets_all')
                    %name_columns = find(contains(app.app_data.loaded_data_cm_ordered_targets_all.Properties.VariableNames,'File'));
                    area_columns = (find(contains(app.app_data.loaded_data_cm_ordered_targets_all.Properties.VariableNames,'Area'),1,"first"));

                    %unique_features_targets = app.app_data.loaded_data_cm_ordered_targets_all.Properties.VariableNames((max(name_columns)+1):width(app.app_data.loaded_data_cm_ordered_targets_all))
                    unique_features_targets = app.app_data.loaded_data_cm_ordered_targets_all.Properties.VariableNames(area_columns:width(app.app_data.loaded_data_cm_ordered_targets_all))

                end

                if exist('unique_features_references','var')
                    unique_features = horzcat(unique_features_references);
                    if  exist('unique_features_targets','var')
                        unique_features = horzcat(unique_features_references, unique_features_targets);
                    end
                elseif exist('unique_features_targets','var')
                    unique_features = horzcat(unique_features_targets);
                end

                app.FeaturesListBox.Items = unique_features;
            end

        end

        function [app] = merge_all_tables(app)

            targets_found = 0;
            references_found = 0;

            if isfield(app.app_data,'loaded_data_cm_ordered_references_all')
                references_table_to_plot = app.app_data.loaded_data_cm_ordered_references_all;
                file_name_cols = find(contains(references_table_to_plot.Properties.VariableNames,'Name','IgnoreCase',true));

                % add a column of the row type (reference or target)
                Type = (repmat({'reference'},[height(references_table_to_plot),1]));
                references_found = 1;
                references_table_to_plot = addvars(references_table_to_plot, Type, 'After',max(file_name_cols));
            end

            if isfield(app.app_data,'loaded_data_cm_ordered_targets_all')
                targets_table_to_plot = app.app_data.loaded_data_cm_ordered_targets_all;
                file_name_cols = find(contains(targets_table_to_plot.Properties.VariableNames,'Name','IgnoreCase',true));

                % add a column of the row type (reference or target)
                Type = (repmat({'target'},[height(targets_table_to_plot),1]));
                targets_found = 1;
                targets_table_to_plot = addvars(targets_table_to_plot, Type, 'After',max(file_name_cols));
            end

            if references_found
                all_data_to_plot = vertcat(references_table_to_plot);

                if targets_found

                    try
                        all_data_to_plot = vertcat(references_table_to_plot, targets_table_to_plot);
                    catch
                        message = 'cannot join tables, make sure columns have the same titles';
                        update_board(app,message);
                        msgbox(message,"Error","error");
                        error(message)
                    end

                end
            elseif targets_found

                all_data_to_plot = vertcat(targets_table_to_plot);

            end

            type_name_cols = find(contains(all_data_to_plot.Properties.VariableNames,'Type','IgnoreCase',true));
            all_data_to_plot_only_data = all_data_to_plot(:,((type_name_cols+1):width(all_data_to_plot)));
            all_data_to_plot_only_descriptors = all_data_to_plot(:,(1:(type_name_cols)));

            app.app_data.all_data_to_plot = all_data_to_plot;
            app.app_data.all_data_to_plot_only_data = all_data_to_plot_only_data;
            app.app_data.all_data_to_plot_only_descriptors = all_data_to_plot_only_descriptors;

        end

        function [app] = calculate_pca(app)
            % get only the selected samples
            all_data_to_plot = app.app_data.all_data_to_plot;
            rows_to_plot = app.app_data.rows_to_plot;

            all_data_to_pca = all_data_to_plot(rows_to_plot,:);
            % get only the references
            references_to_pca = all_data_to_pca(strcmp(all_data_to_pca.Type,'reference'),:);
            targets_to_pca = all_data_to_pca(strcmp(all_data_to_pca.Type,'target'),:);

            first_data_col = find(contains(references_to_pca.Properties.VariableNames,'Area','IgnoreCase',true),1,"first");
            references_to_pca_data = references_to_pca(:,[first_data_col:width(references_to_pca)]);
            references_to_pca_data_norm = zscore(table2array(references_to_pca_data),1);
            references_to_pca_descriptors = references_to_pca(:,[1:(first_data_col-1)]);

            % see if the user selected a color variable
            try
                % case 1 - the user defined a specific color column
                color_col_ind = find(strcmp(references_to_pca_descriptors.Properties.VariableNames,app.app_data.color_column),1,"first");
                1+1
            catch

                try
                    % case 2 - the user did defined a specific color column
                    % - using the category column
                    color_col_ind = find(strcmp(references_to_pca_descriptors.Properties.VariableNames,app.app_data.category_column),1,"first");
                    2+2
                catch

                    % case 3 - could not detect either color nor category
                    % column
                    color_col_ind = 1;
                    3+3
                end
            end


            [Groups_references,ID_references] = findgroups(references_to_pca_descriptors(:,color_col_ind));

            targets_to_pca_data = targets_to_pca(:,[first_data_col:width(targets_to_pca)]);
            targets_to_pca_data_norm = zscore(table2array(targets_to_pca_data),1);
            targets_to_pca_descriptors = targets_to_pca(:,[1:(first_data_col-1)]);

            %[Groups_targets,ID_targets] = findgroups(targets_to_pca_descriptors(:,1));
            % color_col_ind = find(strcmp(targets_to_pca_descriptors.Properties.VariableNames,app.app_data.color_column),1,"first");
            [Groups_targets,ID_targets] = findgroups(targets_to_pca_descriptors(:,color_col_ind));

            % calculate the PCA
            [coeff,score,latent,tsquared,explained,mu] = pca(references_to_pca_data_norm,'NumComponents',size(references_to_pca_data_norm,2));

            % Project the targets data on the same PCA
            % https://www.mathworks.com/matlabcentral/answers/796667-scaling-new-data-to-preoject-them-onto-my-pca-space
            % coeff = coeff(:, [1:size(coeff,2)]); % Keep the first two principal components
            stddev = std(references_to_pca_data_norm); % Calculate standard deviation of the original dataset
            newPointStandardized = (targets_to_pca_data_norm - mu) ./ stddev; % Standardize the new point
            newPointProjected = newPointStandardized * coeff; % Project the new point onto the PCA axes

            app.app_data.pca.Groups_references = Groups_references;
            app.app_data.pca.ID_references = ID_references;
            app.app_data.pca.references_to_pca_descriptors = references_to_pca_descriptors;

            app.app_data.pca.Groups_targets = Groups_targets;
            app.app_data.pca.ID_targets = ID_targets;
            app.app_data.pca.targets_to_pca_descriptors = targets_to_pca_descriptors;

            app.app_data.pca.coeff = coeff;
            app.app_data.pca.score = score;
            app.app_data.pca.latent = latent;
            app.app_data.pca.tsquared = tsquared;
            app.app_data.pca.explained = explained;
            app.app_data.pca.mu = mu;

            app.app_data.pca.stddev = stddev;
            app.app_data.pca.newPointStandardized = newPointStandardized;
            app.app_data.pca.newPointProjected = newPointProjected;


        end

        function [app] = prepare_data_to_plot(app)

            % update the category column for the plot
            app.app_data.category_column = app.categoryDropDown.Value;

            % get the category column defined by the user
            category_column = app.app_data.category_column;

            all_data_to_plot = app.app_data.all_data_to_plot;
            all_data_to_plot_only_data = app.app_data.all_data_to_plot_only_data;
            all_data_to_plot_only_descriptors = app.app_data.all_data_to_plot_only_descriptors;

            % get the rows to be plotted
            selected_samples = [app.ReferencesListBox.Value, app.TargetsListBox.Value];
            rows_to_plot = contains(all_data_to_plot{:,category_column},selected_samples);

            % define the data to be plotted
            col_to_plot = app.FeaturesListBox.Value{1};
            y_to_plot = table2array(all_data_to_plot(rows_to_plot,col_to_plot));
            groups_to_plot = all_data_to_plot{rows_to_plot,category_column};
            types_to_plot = all_data_to_plot{rows_to_plot,'Type'};

            % sort the columns based on type to get the references before
            % the targets
            tmp_T = [types_to_plot,groups_to_plot];
            sorted_t = sortrows(tmp_T,[1,2]);

            % get the groups to be used for the plot
            unique_groups_to_plot = unique(sorted_t(:,2),'stable');

            % update relevant variables to plot
            app.app_data.rows_to_plot = rows_to_plot;
            app.app_data.y_to_plot = y_to_plot;
            app.app_data.groups_to_plot = groups_to_plot;
            app.app_data.types_to_plot = types_to_plot;
            app.app_data.sorted_t = sorted_t;
            app.app_data.unique_groups_to_plot = unique_groups_to_plot;
            app.app_data.col_to_plot = col_to_plot;
        end

        function [app] = select_export_folder(app)
            user_export_dir_path = uigetdir(); % ask user for data directory
            user_export_dir_path_only_folder = user_export_dir_path(max(regexp(user_export_dir_path,'\'))+1:end);

            app.app_data.user_export_dir_path = user_export_dir_path;
            app.app_data.user_export_dir_path_only_folder = user_export_dir_path_only_folder;
        end

        function [app] = create_export_all_folder(app)
            plot_type = app.AnalysesKnob.Value;
            user_export_dir_path = app.app_data.user_export_dir_path;

            jj = 1;

            while jj
                % create a folder with the new counter value
                current_save_all_folder = fullfile(user_export_dir_path,strjoin({'PitAnalyzer_export_all',plot_type, num2str(jj)},'_'));
                % Finally, create the folder if it doesn't exist already.
                if ~exist(current_save_all_folder, 'dir')
                    mkdir(current_save_all_folder);
                    app.app_data.current_save_all_folder = current_save_all_folder;
                    break
                else
                    jj = jj+1;
                end
            end
        end

        function [app] = create_file_name_for_export(app)
            plot_type = app.app_data.plot_type;

            if ismember(plot_type,{'Boxplots','CDF','PDE'})
                file_name_for_export = strjoin({app.app_data.plot_type, app.app_data.col_to_plot},'_');
                file_name_for_export = strjoin({file_name_for_export,'.',app.app_data.file_format},'');

                current_save_all_folder = app.app_data.current_save_all_folder;
                file_name_for_export = fullfile(current_save_all_folder, file_name_for_export);
                app.app_data.file_name_for_export = file_name_for_export;

            elseif ismember(plot_type,{'PCA'})

                pca_plot_type = app.app_data.pca_plot_type;
                pca_plot_ind = app.app_data.pca_plot_ind;

                file_name_for_export = strjoin({app.app_data.plot_type,pca_plot_type,num2str(pca_plot_ind)},'_');
                file_name_for_export = strjoin({file_name_for_export,'.',app.app_data.file_format},'');

                current_save_all_folder = app.app_data.current_save_all_folder;
                file_name_for_export = fullfile(current_save_all_folder, file_name_for_export);
                app.app_data.file_name_for_export = file_name_for_export;

            end

        end

        function [app] = export_figure(app)
            current_axes = app.UIAxes;
            current_resolution = app.app_data.defaults.dpi.dpi_for_saving_small_figs;

            % export png
            app.app_data.file_format = 'png';
            [app] = create_file_name_for_export(app);
            exportgraphics(current_axes,app.app_data.file_name_for_export,"Resolution",current_resolution,'ContentType','image', 'Padding', 'figure');

            % export pdf
            app.app_data.file_format = 'pdf';
            [app] = create_file_name_for_export(app);
            exportgraphics(current_axes,app.app_data.file_name_for_export,'ContentType','vector', 'Padding', 'figure');

        end

        function [app] = update_merge_variable(app)
            % this function updates the organizing variable and looks for the
            % "View" variable
            % load the first csv file to get the column titles:
            % continue here

            %% load a single file to get the headers
            data_dir_path = app.app_data.user_data_references_dir_path;
            app.app_data.current_folder = data_dir_path;
            app = get_csv_list(app);
            csvfiles_table = app.app_data.csvfiles_table;

            loaded_data_tables_names = csvfiles_table.name;
            loaded_data_table = readtable(fullfile(data_dir_path,loaded_data_tables_names{1}));

            loaded_variable_names = loaded_data_table.Properties.VariableNames;

            % update the dropdown menu with the column titles
            app.variableDropDown.Items = loaded_variable_names;

            %% look for the grain View column
            try
                grain_view_data_col = find(contains(loaded_variable_names,'View','IgnoreCase',true),1,'first');
                app.variableDropDown.ValueIndex = grain_view_data_col;
            catch

            end

            %% update the relevant variables
            selected_merge_col = app.variableDropDown.Value;
            selected_merge_col_index = app.variableDropDown.ValueIndex;

            app.app_data.selected_merge_col = selected_merge_col;
            app.app_data.selected_merge_col_index = selected_merge_col_index;

            %% notify user
            app.variableDropDownLabel.Text = selected_merge_col;
            message = strjoin({'organizing table by:', (app.app_data.selected_merge_col)},' ');
            msgbox(message);
            update_board(app,message)

        end

        function [app] = get_folder_type(app)
            % get the type of folder to be loaded and notify the user
            % check which folder is it
            folder_type = app.app_data.folder_type;

            % load the references data
            if strcmp(folder_type,'references_folder')
                message  = 'loading references data';
                current_folder = app.app_data.user_data_references_dir_path;
                app.app_data.current_folder = current_folder;

                % load the targets data
            elseif strcmp(folder_type,'targets_folder')
                message  = 'loading targets data';
                current_folder = app.app_data.user_data_targets_dir_path;
                app.app_data.current_folder = current_folder;

            else
                message = 'no data folders were set';
            end

            update_board(app,message);
        end

        function [app] = get_csv_list(app)
            %% check if a CSV file exists
            data_dir_path = app.app_data.current_folder;

            csvfiles = dir(fullfile(data_dir_path, '**', '*.csv'));
            %  csvfiles_cell = fullfile({csvfiles.folder}, {csvfiles.name});

            % look for existing CSV files
            % csvfiles = dir(stats_dir_path, '*.CSV');
            csvfiles_table = struct2table(csvfiles);
            n_csv_files = height(csvfiles_table);

            %% notify the user how many files were found
            % if no csv was found
            if isempty(csvfiles) % no csv file was found
                load_csv_file = 0;

                message = 'no csv datafile found!';
                update_board(app,message);
                msgbox(message,"Error","error");
                error(message);
                stop;

                % more than one csv was found
            else % some csv files were found
                TF = contains(csvfiles_table.name,"pit_analyzer_stats_results",'IgnoreCase',true);
                if sum(sum(TF)) > 1

                    % if more than file was found stop and notify user
                    message = [strjoin({num2str(n_csv_files), 'csv files were found'},' ')];


                    % a single csv file was found
                elseif sum(sum(TF)) == 1
                    message = strjoin({'a single csv file:', csvfiles_table.name ,'was found'},' ');
                    pause(1)
                end
            end
            update_board(app,message)


            app.app_data.data_dir_path = data_dir_path;
            app.app_data.csvfiles_table = csvfiles_table;
            app.app_data.n_csv_files = n_csv_files;
        end

        function [app] = organize_table_by_selected_col(app)

            %%
            try
                grain_merge_vars_data_col = app.app_data.selected_merge_col_index;

                current_table_ind = app.app_data.loaded_data_tables_index;
                current_table = app.app_data.loaded_data_tables_cm{current_table_ind};

                % grain_view_data_col = find(contains(current_table.Properties.VariableNames,'View','IgnoreCase',true),1,'first');

                selected_merge_col_index = app.app_data.selected_merge_col_index;
                merge_vars_unique = unique(current_table{:,selected_merge_col_index});

                current_table_merge_vars_indices = cell(length(merge_vars_unique),1);
                current_table_merge_vars_data = cell(length(merge_vars_unique),1);

                for ii = 1:length(merge_vars_unique)
                    current_table_merge_vars_indices{ii} =  strcmp(current_table{:,grain_merge_vars_data_col},merge_vars_unique{ii});
                    current_table_merge_vars_data{ii} =  current_table(current_table_merge_vars_indices{ii},:);
                end
                %%
                %
                % % get indices of VD images
                % current_table_VD_indices = contains(current_table.StoneView,'VD','IgnoreCase',true);
                % current_table_VD = current_table(current_table_VD_indices,:);
                %
                % % get indices of VL images
                % current_table_VL_indices = contains(current_table.StoneView,'VL','IgnoreCase',true);
                % current_table_VL = current_table(current_table_VL_indices,:);

                first_data_col = find(contains(current_table.Properties.VariableNames,'Area','IgnoreCase',true),1,'first');
                file_name_col = find(contains(current_table.Properties.VariableNames,'FileName','IgnoreCase',true),1,'first');

                % add the letters VD and VL to the columns with the measure
                % variables
                % current_table_VD.Properties.VariableNames([file_name_col,first_data_col:width(current_table_VD)]) = strcat(current_table_VD.Properties.VariableNames(first_data_col:width(current_table_VD)),'_VD');
                % current_table_VL.Properties.VariableNames([file_name_col,first_data_col:width(current_table_VL)]) = strcat(current_table_VL.Properties.VariableNames(first_data_col:width(current_table_VL)),'_VL');
                %
                % % reorder columns
                % current_table_VD = current_table_VD(:,[(2:first_data_col-1),file_name_col,(first_data_col:width(current_table_VD))]);
                % current_table_VL = current_table_VL(:,[(2:first_data_col-1),file_name_col,(first_data_col:width(current_table_VD))]);


                ordered_tables = cell(length(merge_vars_unique),1);

                for ii = 1:length(merge_vars_unique)

                    tmp_table = current_table_merge_vars_data{ii};
                    tmp_table.Properties.VariableNames([file_name_col,first_data_col:width(tmp_table)]) = ...
                        strcat(tmp_table.Properties.VariableNames([file_name_col,first_data_col:width(tmp_table)]),strcat('_',merge_vars_unique{ii}));
                    %%
                    % remove the grain merge column
                    % grain_view_data_col = find(contains(tmp_table.Properties.VariableNames,'View','IgnoreCase',true),1,'first');
                    tmp_table(:,selected_merge_col_index) = [];

                    first_data_col_updated = find(contains(tmp_table.Properties.VariableNames,'Area','IgnoreCase',true),1,'first');

                    % reorder the file name column
                    tmp_table = tmp_table(:,[(2:first_data_col_updated-1),file_name_col,(first_data_col_updated:width(tmp_table))]);

                    ordered_tables{ii} = tmp_table;
                end

                % assuming only two photos exist per grain
                merged_table = ordered_tables{1};
                if height(ordered_tables{ii}) > 1
                    for ii = 2:length(merge_vars_unique)
                        try
                            merged_table = join(merged_table, ordered_tables{ii});
                        catch
                            message = 'cannot join tables, make sure columns have the same titles';
                            update_board(app,message);
                            msgbox(message,"Error","error");
                            error(message)
                            stop;
                        end
                    end
                end

                %%
                % % assuming all columns before the file name are the file
                % % identity descriptors
                % file_name_cols = find(contains(merged_table.Properties.VariableNames,'Name','IgnoreCase',true));
                % all_cols_ind = [(min(file_name_cols)):width(merged_table)];
                % remaining_cols_ind = all_cols_ind(~ismember([all_cols_ind],file_name_cols));
                %
                % % reorder columns
                % merged_table_cm_ordered = merged_table(:,[[1:(min(file_name_cols)-1)],file_name_cols,remaining_cols_ind]);
                %
                % % save into variable
                % app.app_data.merged_table_cm_ordered{current_table_ind} = merged_table_cm_ordered;

            catch
                message = 'cannot join tables, make sure columns have the same titles';
                update_board(app,message);
                msgbox(message,"Error","error");
                error(message)
            end

        end

        function [app] = update_dropdown(app)

            app.categoryDropDown.Items =  app.app_data.meta_data_cell;
            app.colorDropDown.Items =  app.app_data.meta_data_cell;
            app.shapeDropDown.Items =  app.app_data.meta_data_cell;

            % set the default colomns for the plot
            app.app_data.color_column_ind = app.app_data.meta_data_cell{app.app_data.color_column_ind};
            app.app_data.category_column_ind = app.app_data.meta_data_cell{app.app_data.category_column_ind};
            app.app_data.shape_column_ind = app.app_data.meta_data_cell{app.app_data.shape_column_ind};


        end

        function [app] = Pit_analyzer_get_file_name(app)
            %% get the file name
            currentfilename = app.app_data.currentfilename;

            % remove the format
            % currentfilename_format_ind = strfind(currentfilename,strcat('.',app.app_data.image_file_format));
            % update 31-Aug-2025
            currentfilename_format_ind = regexpi(currentfilename,strcat('.',app.app_data.image_file_format));
            currentfilename_format_ind = currentfilename_format_ind{1};

            currentfilename_format_no_format = currentfilename(1:(currentfilename_format_ind-1));
            currentfilename_format_no_format = char(currentfilename_format_no_format);

            app.app_data.currentfilename_format_no_format = currentfilename_format_no_format;

        end

        function [app] = Pit_analyzer_align_grain(app)
            % PIT_ANALYZER_ALIGN_GRAIN Aligns a binary grain silhouette horizontally along
            % the longest axis that passes directly through its centroid.
            %
            % INPUT:
            %   BWoutline         - 2D binary image matrix saved in app.app_data.additional_images{3}
            %
            % OUTPUT:
            %   BWoutline_aligned - 2D binary matrix aligned along its
            %   maximum centroid axis saved into app.app_data.BWoutline_aligned

            %% Prepare the image for alignment
            BWoutline = app.app_data.tmp_perimeter; %app.app_data.additional_images{3};

            % display the image to be aligned
            figure;imshow(BWoutline)
            BW = logical(BWoutline);
            %%
            BW = logical(BW);

            % 1. Background check: invert white background so imrotate's 0-padding
            % matches background color (prevents dark border streaks)
            is_white_bg = mean(BW(:)) > 0.5;
            if is_white_bg
                work_img = ~BW; % Foreground = 1 (white), Background = 0 (black)
            else
                work_img = BW;
            end

            % =========================================================================
            % 2. CREATE FULL SOLID GRAIN BEFORE ROTATION
            % =========================================================================
            % Detect if input was an outline (shell) or solid silhouette
            filled_temp = imfill(work_img, 'holes');
            is_outline_input = sum(filled_temp(:)) > (1.2 * sum(work_img(:)));

            % Force full solid body to prevent thin shell line disintegration during rotation
            solid_grain = bwareafilt(filled_temp, 1);

            if ~any(solid_grain(:))
                warning('No object detected in input image.');
                aligned_BW = BW;
                return;
            end

            % 3. Calculate Centroid [X, Y] on solid grain
            stats = regionprops(solid_grain, 'Centroid');
            cx = stats(1).Centroid(1); % Centroid X (columns)
            cy = stats(1).Centroid(2); % Centroid Y (rows)

            % 4. Find max chord angle passing through centroid
            [h, w] = size(solid_grain);
            max_radius = ceil(sqrt(h^2 + w^2) / 2);
            step_size = 0.5;
            t = -max_radius : step_size : max_radius;
            zero_idx = find(t == 0);

            angles = 0 : 0.5 : 179.5;
            max_chord_len = 0;
            best_angle = 0;

            img_double = double(solid_grain);

            for a = angles
                x_line = cx + t * cosd(a);
                y_line = cy - t * sind(a);

                vals = interp2(img_double, x_line, y_line, 'linear', 0) > 0.5;

                if ~vals(zero_idx)
                    continue;
                end

                left_idx = zero_idx;
                while left_idx > 1 && vals(left_idx - 1)
                    left_idx = left_idx - 1;
                end

                right_idx = zero_idx;
                while right_idx < length(t) && vals(right_idx + 1)
                    right_idx = right_idx + 1;
                end

                chord_len = (right_idx - left_idx) * step_size;

                if chord_len > max_chord_len
                    max_chord_len = chord_len;
                    best_angle = a;
                end
            end

            % =========================================================================
            % 5. ROTATE FULL SOLID GRAIN (No holes can form)
            % =========================================================================
            rot_solid = imrotate(solid_grain, -best_angle, 'nearest', 'loose');
            rot_solid = imfill(rot_solid, 'holes'); % Safety re-fill

            % =========================================================================
            % 6. CENTER CENTROID IN OUTPUT CANVAS
            % =========================================================================
            rot_stats = regionprops(rot_solid, 'Centroid', 'BoundingBox', 'Area');
            [~, rot_max_idx] = max([rot_stats.Area]);

            rot_cx = rot_stats(rot_max_idx).Centroid(1);
            rot_cy = rot_stats(rot_max_idx).Centroid(2);

            bbox = rot_stats(rot_max_idx).BoundingBox;
            xmin = bbox(1); ymin = bbox(2);
            xmax = xmin + bbox(3) - 1; ymax = ymin + bbox(4) - 1;

            dist_x = max(abs(rot_cx - xmin), abs(xmax - rot_cx));
            dist_y = max(abs(rot_cy - ymin), abs(ymax - rot_cy));

            margin = 10;
            half_w = ceil(dist_x) + margin;
            half_h = ceil(dist_y) + margin;

            x_start = round(rot_cx) - half_w;
            x_end   = round(rot_cx) + half_w;
            y_start = round(rot_cy) - half_h;
            y_end   = round(rot_cy) + half_h;

            pad_top    = max(0, 1 - y_start);
            pad_bottom = max(0, y_end - size(rot_solid, 1));
            pad_left   = max(0, 1 - x_start);
            pad_right  = max(0, x_end - size(rot_solid, 2));

            padded_rot = padarray(rot_solid, [pad_top, pad_left], 0, 'pre');
            padded_rot = padarray(padded_rot, [pad_bottom, pad_right], 0, 'post');

            y_start_adj = y_start + pad_top;
            y_end_adj   = y_end + pad_top;
            x_start_adj = x_start + pad_left;
            x_end_adj   = x_end + pad_left;

            centered_solid = padded_rot(y_start_adj:y_end_adj, x_start_adj:x_end_adj);

            % =========================================================================
            % 7. CONVERT BACK TO OUTLINE (IF INPUT WAS OUTLINE) & RESTORE COLOR
            % =========================================================================
            if is_outline_input
                % Extract continuous 8-connected perimeter shell from solid body
                final_fg = bwperim(centered_solid);
            else
                final_fg = centered_solid;
            end

            if is_white_bg
                aligned_BW = ~final_fg;
            else
                aligned_BW = final_fg;
            end


            %% save and export variables

            BWoutline_aligned = aligned_BW;


            %% present both images to the user
            montage_figure = figure('units','normalized','outerposition',[0 0 1 1]);
            montage_ax = gca;
            montage({BWoutline,BWoutline_aligned},"Size",[1,2],"Parent",montage_ax);

            pause(0.5)
            filename_for_saving = app.app_data.currentfilename_format_no_format;

            stats_dir_path = app.app_data.stats_dir_path;

            tmp_title = filename_for_saving;
            title(strjoin({tmp_title,'|','Alignment'},' '),'Interpreter','none');
            tmp_file_name = strcat(strjoin({tmp_title,'_','aligner_segmentation results'},' '),'.png');
            tmp_file_name = fullfile(stats_dir_path, tmp_file_name);
            update_board(app,tmp_file_name)

            exportgraphics(gcf(),tmp_file_name,"Resolution", app.app_data.defaults.dpi.dpi_for_saving_small_figs, 'Padding', 'figure')

            %% save into variable
            app.app_data.BWoutline_aligned = BWoutline_aligned;


        end

        function [app] = Pit_analyzer_calculate_EFT(app)
            % PIT_ANALYZER_CALCULATE_EFT Performs Elliptical Fourier Transform (EFT) on
            % an aligned grain silhouette stored in the App structure.
            %
            % INPUT:
            %   app - App Designer handle or structure containing app_data
            %
            % OUTPUT:
            %   app - Updated App handle with calculated EFT features saved in app_data

            % =========================================================================
            % 1. RETRIEVE INPUTS FROM APP STRUCTURE
            % =========================================================================
            % Check harmonic count setting (default = 20)
            if isfield(app.app_data, 'num_harmonics') && ~isempty(app.app_data.num_harmonics)
                num_harmonics = app.app_data.num_harmonics;
            else
                num_harmonics = 20;
            end

            1+1

            % Get current image index and aligned image matrix
            current_image_analyzed_ind = app.app_data.current_image_analyzed_ind;
            aligned_BW = app.app_data.loaded_images_BWoutline_aligned_cell{current_image_analyzed_ind};
            aligned_BW = logical(aligned_BW);

            % =========================================================================
            % 2. PREPARE SILHOUETTE FOR BOUNDARY TRACKING
            % =========================================================================
            % Auto-detect background color and convert foreground to white (1s)
            if mean(aligned_BW(:)) > 0.5
                BW_fg = ~aligned_BW; % Invert white background -> black=0, white=1
            else
                BW_fg = aligned_BW;
            end

            % Fill holes so that an outline/shell becomes a solid 2D mask.
            % This guarantees bwboundaries tracks a single outer perimeter.
            BW_solid = imfill(BW_fg, 'holes');
            BW_solid = bwareafilt(BW_solid, 1); % Ensure single largest component

            % =========================================================================
            % 3. EXTRACT OUTLINE BOUNDARY COORDINATES
            % =========================================================================
            boundaries = bwboundaries(BW_solid, 'noholes');
            if isempty(boundaries)
                error('No valid boundary detected in aligned_BW at index %d.', current_image_analyzed_ind);
            end

            raw_boundary = boundaries{1};
            x = raw_boundary(:, 2); % Matrix columns = X coordinates
            y = raw_boundary(:, 1); % Matrix rows = Y coordinates
            contour_pts = [x, y];

            app.app_data.contour_pts = contour_pts;
            app.app_data.num_harmonics = num_harmonics;

            % =========================================================================
            % 4. COMPUTE RAW & NORMALIZED FOURIER DESCRIPTORS
            % =========================================================================
            % Calculate raw EFD coefficients [a_n, b_n, c_n, d_n] and centroid offset [x0, y0]
            % [a, b, c, d, x0, y0] = compute_efd(contour_pts, num_harmonics);
            [app] = compute_efd(app);

            efd_struct = app.app_data.efd_struct;
            efd_raw = [efd_struct.a, efd_struct.b, efd_struct.c, efd_struct.d];

            % Calculate invariant normalized coefficients [a_n*, b_n*, c_n*, d_n*]
            %[a_norm, b_norm, c_norm, d_norm] = normalize_efd_coeffs(a, b, c, d);

            [app] = normalize_efd_coeffs(app);
            efd_norm_struct =  app.app_data.efd_norm;
            efd_norm = [efd_norm_struct.a_norm, efd_norm_struct.b_norm, efd_norm_struct.c_norm, efd_norm_struct.d_norm];

            % Reconstruct boundary points from Fourier harmonics for visual validation
            num_rec_pts = 500;
            app.app_data.reconstruct_points = 500;

            %[rec_x, rec_y] = reconstruct_contour(a, b, c, d, x0, y0, num_rec_pts);
            [app] = reconstruct_contour(app);

            rec_x = app.app_data.rec_x;
            rec_y = app.app_data.rec_y;

            rec_coords = [rec_x', rec_y'];

            % =========================================================================
            % 5. SAVE ALL OUTPUTS DIRECTLY INTO APP STRUCTURE
            % =========================================================================
            app.app_data.EFT_raw_cell{current_image_analyzed_ind}        = efd_raw;        % [num_harmonics x 4]
            app.app_data.EFT_norm_cell{current_image_analyzed_ind}       = efd_norm;       % [num_harmonics x 4] (size, rotation, phase invariant)
            app.app_data.EFT_rec_coords_cell{current_image_analyzed_ind} = rec_coords;     % [500 x 2] reconstructed (x,y)
            app.app_data.EFT_contour_pts_cell{current_image_analyzed_ind}= contour_pts;    % [N x 2] extracted raw boundary (x,y)
            app.app_data.EFT_centroid_cell{current_image_analyzed_ind}   = [app.app_data.efd_struct.x0, app.app_data.efd_struct.y0];       % [1 x 2] centroid DC component

            %% plot the EFT reconstruction and export
            % Pit_analyzer_plot_EFT_additive(app)

            stats_dir_path = app.app_data.stats_dir_path;
            filename_for_saving = app.app_data.currentfilename_format_no_format;

            tmp_title = filename_for_saving;
            tmp_file_name = strcat(strjoin({tmp_title,'_','EFT_results'},''),'.png');
            tmp_file_name = fullfile(stats_dir_path, tmp_file_name);
            update_board(app,tmp_file_name)

            % 1. Create an undocked standalone figure with large canvas dimensions [left, bottom, width, height]
            target_width  = 1800; % Pixels
            target_height = 1350; % Pixels

            % Set 'Visible' to 'on' if you want it to pop up as an undocked window, or 'off' to run in background
            fig = figure('Name', 'Export Window', 'Visible', 'off', 'Color', 'w');
            fig.Position = [100, 100, target_width, target_height];

            % 2. Create axes inside the standalone figure
            ax = axes(fig);

            % 3. Re-render your EFT plot onto the new axes
            Pit_analyzer_plot_EFT_additive(app, ax);

            % 4. Optional: Adjust font sizes and line widths for the larger canvas
            set(ax, 'FontSize', 14);

            % 5. Export with high DPI and tight padding
            exportgraphics(fig, tmp_file_name, ...
                'Resolution', 300, ...          % High DPI (e.g., 300 or 600)
                'Padding', 'tight');            % 'tight' removes extra whitespace around edges

            % 6. Clean up off-screen figure handle
            close(fig);

            %%
            app.app_data.num_harmonics_to_export = app.app_data.defaults.num_harmonics_to_export;
            [app] = Pit_analyzer_convert_efd_to_table(app)

            %%

            message = "EFT completed and exported";
            update_board(app,message);
        end

        function [app] = compute_efd(app)
            %function [a, b, c, d, x0, y0] = compute_efd(boundary, N)
            % =========================================================================
            % SUBFUNCTION 1: Compute Raw EFDs (Kuhl & Giardina 1982 Algorithm)
            % =========================================================================

            contour_pts = app.app_data.contour_pts;
            num_harmonics = app.app_data.num_harmonics;

            x = contour_pts(:, 1);
            y = contour_pts(:, 2);
            K = length(x);

            % Close boundary loop if endpoints do not meet
            if x(1) ~= x(end) || y(1) ~= y(end)
                x = [x; x(1)];
                y = [y; y(1)];
                K = K + 1;
            end

            dx = diff(x);
            dy = diff(y);
            dt = sqrt(dx.^2 + dy.^2);
            dt(dt == 0) = 1e-10; % Avoid division by zero for identical consecutive pixels

            t = [0; cumsum(dt)];
            T = t(end); % Total perimeter length

            % Spatial centroid (DC component)
            x0 = mean(x(1:end-1));
            y0 = mean(y(1:end-1));

            a = zeros(num_harmonics, 1); b = zeros(num_harmonics, 1);
            c = zeros(num_harmonics, 1); d = zeros(num_harmonics, 1);

            for n = 1:num_harmonics
                factor = T / (2 * (n^2) * (pi^2));

                cos_t = cos((2 * n * pi * t) / T);
                sin_t = sin((2 * n * pi * t) / T);

                d_cos = cos_t(2:end) - cos_t(1:end-1);
                d_sin = sin_t(2:end) - sin_t(1:end-1);

                a(n) = factor * sum((dx ./ dt) .* d_cos);
                b(n) = factor * sum((dx ./ dt) .* d_sin);
                c(n) = factor * sum((dy ./ dt) .* d_cos);
                d(n) = factor * sum((dy ./ dt) .* d_sin);

                efd_struct.a = a;
                efd_struct.b = b;
                efd_struct.c = c;
                efd_struct.d = d;
                efd_struct.x0 = x0;
                efd_struct.y0 = y0;

            end

            app.app_data.efd_struct = efd_struct;
        end

        function [app] = normalize_efd_coeffs(app)
            %function [a_norm, b_norm, c_norm, d_norm] = normalize_efd_coeffs(a, b, c, d)
            % =========================================================================
            % SUBFUNCTION 2: Coefficient Normalization (Scale, Rotation, Phase Invariance)
            % =========================================================================

            efd_struct = app.app_data.efd_struct;
            a = efd_struct.a;
            b = efd_struct.b;
            c = efd_struct.c;
            d = efd_struct.d;

            N = length(a);

            % Angle of first harmonic ellipse major axis
            theta1 = 0.5 * atan2(2*(a(1)*b(1) + c(1)*d(1)), (a(1)^2 + c(1)^2 - b(1)^2 - d(1)^2));

            a1_star = a(1)*cos(theta1) + b(1)*sin(theta1);
            c1_star = c(1)*cos(theta1) + d(1)*sin(theta1);

            psi1 = atan2(c1_star, a1_star);
            E1 = sqrt(a1_star^2 + c1_star^2); % Semi-major axis scale factor

            a_norm = zeros(N, 1); b_norm = zeros(N, 1);
            c_norm = zeros(N, 1); d_norm = zeros(N, 1);

            for n = 1:N
                rot_mat = [cos(psi1), sin(psi1); -sin(psi1), cos(psi1)];
                harm_mat = [cos(n*theta1), -sin(n*theta1); sin(n*theta1), cos(n*theta1)];

                coef_orig = [a(n), b(n); c(n), d(n)];
                coef_norm = (1 / E1) * rot_mat * coef_orig * harm_mat;

                a_norm(n) = coef_norm(1, 1);
                b_norm(n) = coef_norm(1, 2);
                c_norm(n) = coef_norm(2, 1);
                d_norm(n) = coef_norm(2, 2);

            end

            efd_norm.a_norm = a_norm;
            efd_norm.b_norm = b_norm;
            efd_norm.c_norm = c_norm;
            efd_norm.d_norm = d_norm;

            app.app_data.efd_norm = efd_norm;

        end

        function [app] = reconstruct_contour(app)
            % function [rec_x, rec_y] = reconstruct_contour(a, b, c, d, x0, y0, num_pts)

            % =========================================================================
            % SUBFUNCTION 3: Inverse Reconstruction from Fourier Coefficients
            % =========================================================================

            a = app.app_data.efd_struct.a;
            b = app.app_data.efd_struct.b;
            c = app.app_data.efd_struct.c;
            d = app.app_data.efd_struct.d;
            x0 = app.app_data.efd_struct.x0;
            y0 = app.app_data.efd_struct.y0;
            num_pts = app.app_data.reconstruct_points;

            N = length(a);
            t = linspace(0, 2*pi, num_pts);

            rec_x = ones(1, num_pts) * x0;
            rec_y = ones(1, num_pts) * y0;

            for n = 1:N
                rec_x = rec_x + a(n) * cos(n * t) + b(n) * sin(n * t);
                rec_y = rec_y + c(n) * cos(n * t) + d(n) * sin(n * t);
            end

            app.app_data.rec_x = rec_x;
            app.app_data.rec_y = rec_y;
        end

        function ax = Pit_analyzer_plot_EFT_additive(app, ax_handle, harmonics_to_plot)

            % PIT_ANALYZER_PLOT_EFT_ADDITIVE Plots the original grain outline in bold black
            % and overlays cumulative additive EFD reconstructions for visual analysis.
            %
            % INPUTS:
            %   app               - App Designer handle or structure containing app_data
            %   ax_handle         - (Optional) Axes handle to plot on (e.g., app.UIAxes).
            %                       If omitted or empty, creates a new figure window.
            %   harmonics_to_plot - (Optional) Array of cumulative harmonic levels to overlay.
            %                       e.g., [1, 2, 3, 5, 10, 20]. Default auto-generates key steps.
            %
            % OUTPUT:
            %   ax                - Axes handle containing the plot

            % 1. Retrieve data from app structure
            curr_idx    = app.app_data.current_image_analyzed_ind;
            efd_raw     = app.app_data.EFT_raw_cell{curr_idx};        % [N x 4] matrix [a, b, c, d]
            contour_pts = app.app_data.EFT_contour_pts_cell{curr_idx}; % [K x 2] [X, Y]
            centroid    = app.app_data.EFT_centroid_cell{curr_idx};   % [1 x 2] [x0, y0]

            x0 = centroid(1);
            y0 = centroid(2);
            a = efd_raw(:, 1);
            b = efd_raw(:, 2);
            c = efd_raw(:, 3);
            d = efd_raw(:, 4);
            N_total = length(a);

            % 2. Setup target axes (UIAxes or new figure)
            if nargin < 2 || isempty(ax_handle)
                fig = figure('Color', 'w', 'Name', 'Additive EFD Reconstruction');
                ax = axes(fig);
            else
                ax = ax_handle;
            end
            cla(ax); % Clear previous plots on axes
            hold(ax, 'on');

            % 3. Determine cumulative harmonic steps to display
            if nargin < 3 || isempty(harmonics_to_plot)
                % Default key progression steps up to N_total
                base_steps = [1, 2, 3, 5, 10, 15, 20, 30, 40, 50];
                harmonics_to_plot = base_steps(base_steps <= N_total);
                if harmonics_to_plot(end) ~= N_total
                    harmonics_to_plot = [harmonics_to_plot, N_total];
                end
            else
                % Ensure requested steps do not exceed available calculated harmonics
                harmonics_to_plot = harmonics_to_plot(harmonics_to_plot <= N_total);
            end

            % 4. PLOT ORIGINAL GRAIN OUTLINE IN BOLD BLACK
            plot(ax, contour_pts(:, 1), contour_pts(:, 2), 'k-', ...
                'LineWidth', 3.0, 'DisplayName', 'Original Boundary');

            % 5. CALCULATE & OVERLAY ADDITIVE RECONSTRUCTIONS
            num_steps = length(harmonics_to_plot);
            num_pts = 500; % Points along parametric angle t [0, 2*pi]
            t = linspace(0, 2*pi, num_pts);

            % Distinct color palette for additive layers
            color_map = lines(num_steps);

            for idx = 1:num_steps
                k = harmonics_to_plot(idx);

                % Additive Fourier sum from harmonic 1 up to harmonic k
                rec_x = ones(1, num_pts) * x0;
                rec_y = ones(1, num_pts) * y0;

                for n = 1:k
                    rec_x = rec_x + a(n) * cos(n * t) + b(n) * sin(n * t);
                    rec_y = rec_y + c(n) * cos(n * t) + d(n) * sin(n * t);
                end

                % Plot overlaid additive reconstruction layer
                plot(ax, rec_x, rec_y, '--', 'LineWidth', 1.5, ...
                    'Color', color_map(idx, :), ...
                    'DisplayName', sprintf('EFT Additive (1–%d Harmonics)', k));
            end

            % 6. FORMAT AXES & LEGEND
            axis(ax, 'equal');
            set(ax, 'YDir', 'reverse'); % Align with MATLAB image coordinate convention (Y down)
            grid(ax, 'on');
            title(ax, sprintf('Additive Elliptical Fourier Reconstruction (Image #%d)', curr_idx), ...
                'FontSize', 12, 'FontWeight', 'bold');
            xlabel(ax, 'X (pixels)');
            ylabel(ax, 'Y (pixels)');
            legend(ax, 'Location', 'northeastoutside');
            hold(ax, 'off');
        end
        %%
        function [app] = Pit_analyzer_convert_efd_to_table(app)
            % PIT_ANALYZER_CONVERT_EFD_TO_TABLE Converts normalized EFD cell array data
            % into a MATLAB Table containing the first N harmonics per image and saves
            % the output directly to app.app_data.efd_norm_table.
            %
            % INPUTS:
            %   app           - App Designer handle or structure containing app_data
            %   num_harmonics - (Optional) Number of harmonics to extract (default = 8)
            %
            % OUTPUT:
            %   app           - Updated App structure containing app.app_data.efd_norm_table

            num_harmonics = app.app_data.defaults.num_harmonics_to_export;

            % 1. Verify that normalized EFD cell array exists
            if ~isfield(app.app_data, 'EFT_norm_cell') || isempty(app.app_data.EFT_norm_cell)
                error('No normalized EFT data found in app.app_data.EFT_norm_cell.');
            end

            current_image_analyzed_ind = app.app_data.current_image_analyzed_ind;
            eft_cell = app.app_data.EFT_norm_cell(current_image_analyzed_ind);
            num_images = length(eft_cell);

            % 3. Extract and flatten 8 harmonics per image into a matrix row
            data_matrix = NaN(num_images, num_harmonics * 4);

            for i = 1:num_images
                if ~isempty(eft_cell{i})
                    E = eft_cell{i}; % [N x 4] matrix [a_n, b_n, c_n, d_n]

                    % Determine available harmonics up to requested limit
                    h_count = min(size(E, 1), num_harmonics);

                    a_vec = zeros(1, num_harmonics);
                    b_vec = zeros(1, num_harmonics);
                    c_vec = zeros(1, num_harmonics);
                    d_vec = zeros(1, num_harmonics);

                    a_vec(1:h_count) = E(1:h_count, 1)';
                    b_vec(1:h_count) = E(1:h_count, 2)';
                    c_vec(1:h_count) = E(1:h_count, 3)';
                    d_vec(1:h_count) = E(1:h_count, 4)';

                    % Flatten into 1D row vector: [a01..a08, b01..b08, c01..c08, d01..d08]
                    efd_matrix(i, :) = [a_vec, b_vec, c_vec, d_vec];
                end
            end

            1+1

            % 4. Convert 2D numeric matrix into a MATLAB Table
            % efd_norm_table = array2table(data_matrix, 'VariableNames', var_names);

            % 5. Save resulting table into app structure
            app.app_data.efd_norm_table{current_image_analyzed_ind,:} = efd_matrix;
        end
        %%
    end


    methods (Access = public)

        function results = func22(~)

        end

        function results = func39(~)

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % close previous figure
            close all

            app.app_data = struct();
            app.app_data.defaults=struct();

            % set the defaule scale to unknown
            app.scaleisSwitch.Value = 'unknown';
            app.app_data.scale_known_ind = 0;

            % avoid adjusting the center panel
            app.CenterPanel.Visible = "off";

            % default to 2 cluster for k-means segmentation
            app.app_data.n_clusters = 2;

            % default to no selection of approval button
            set(app.notapprovedButton, "Value",0)
            set(app.approvedButton, "Value",0)
            set(app.unknownButton, "Value",1)
            app.app_data.approved_status = NaN;

            % if not deployed, add the folder with the functions
            if ~isdeployed()
                function_folder = 'G:\My Drive\Research\MATLAB\OliveStones\PitAnalyzerApp';
                addpath(genpath(function_folder))
            end

            % define the and size dpi for saving the images
            % this is agreement with the images of the olivestones
            dpi_for_saving_large_figs = 860;
            dpi_for_saving_small_figs = 300;

            app.app_data.defaults.dpi.dpi_for_saving_large_figs = dpi_for_saving_large_figs;
            app.app_data.defaults.dpi.dpi_for_saving_small_figs = dpi_for_saving_small_figs;

            % predefine table structure
            % "FileName","Cultivar","TreeCode","StoneName","StoneView",...

            stats_table_variable_names = [
                "PixelsToCm","Approved",...
                "Area","Centroid_x","Centroid_y",...
                "Circularity","ConvexArea","Eccentricity",...
                "EquivDiameter","MajorAxisLength","MaxFeretDiameter",...
                "MinorAxisLength","MinFeretDiameter","OrthogonalDiameter",...
                "MaxFeretDiameterControl","Perimeter",...
                "Solidity","FormFactor","Roundness",...
                "Compactness","AspectRatio",...
                ];

            app.app_data.defaults.stats_table_variable_names = stats_table_variable_names;

            % user based descriptors
            % "FileName" - the name of the original jpg file to be analyzed
            % "Cultivar" - the cultivair name
            % "TreeCode" - the code of the tree (e.g., a, b, c, ...)
            % "StoneName" - the name of the stone (e.g., 001, 002, 003,...)
            % "StoneView" - the view of the image (e.g., VD, VL)

            % analyses information
            % "PixelsToCm" - the conversion ratio of pixels to cm as calculated by the
            % "Approved" - did the user approve the image analysis results;

            % morphometric parameters
            % "Area" - the size of the area of the stone in pixels
            % "Centroid_x" - the x coordinate of the stone centroid
            % "Centroid_y" - the y coordinate of the stone centroid
            % "Circularity" - stone circularity
            % "ConvexArea"
            % "Eccentricity"
            % "EquivDiameter"
            % "MajorAxisLength"
            % "MaxFeretDiameter"
            % "MinorAxisLength"
            % "MinFeretDiameter"
            % "OrthogonalDiameter"
            % "MaxFeretDiameterControl" - the max feret diameter calculated again for
            % control of properties acquisition
            % "Perimeter"
            % "Solidity"
            % "FormFactor"
            % "Roundness"
            % "Compactness"
            % "AspectRatio"
            % user
            %"string","string","string","double","string",...

            % stats_table_variable_types = [
            %     "double","double","double",...
            %     "double","double","double",...
            %     "double","double","double",...
            %     "double","double","double",...
            %     "double","double",...
            %     "double","double","double",...
            %     "double","double","double",...
            %     "double"];

            stats_table_variable_types = repelem("double", length(stats_table_variable_names));

            app.app_data.defaults.stats_table_variable_types = stats_table_variable_types;

            stats_table_string_variable = find(strcmpi(stats_table_variable_types,"string"));
            stats_table_double_variable = find(strcmpi(stats_table_variable_types,"double"));

            app.app_data.defaults.stats_table_string_variable = stats_table_string_variable;
            app.app_data.defaults.stats_table_double_variable = stats_table_double_variable;

            app.Hyperlink.URL = "https://creativecommons.org/licenses/by-nc-sa/4.0/";


            % mae load csvs button hidden until folders are provided
            app.loadCSVsButton.Visible = "off";
            app.savemergeddataButton.Visible = "off";

            app.ReferencesListBox.Visible = "off";
            app.referencesListBoxLabel.Visible = "off";

            app.TargetsListBox.Visible = "off";
            app.targetsListBoxLabel.Visible = "off";

            app.FeaturesListBox.Visible = "off";
            app.FeaturesListBoxLabel.Visible = "off";

            app.AnalysesKnob.Visible = "off";
            app.AnalysesKnobLabel.Visible = "off";

            app.selectallButton_2.Visible = "off";
            app.selectallButton_3.Visible = "off";
            app.clearselectionButton.Visible = "off";
            app.clearselectionButton_2.Visible = "off";
            app.plotButton.Visible = "off";
            app.exportallfiguresButton.Visible = "off";

            app.PC1DropDown.Visible = "off";
            app.PC1DropDownLabel.Visible = "off";

            app.PC2DropDown.Visible = "off";
            app.PC2DropDownLabel.Visible = "off";

            app.app_data.export_all_break = 0;

            app.app_data.merge_rows = 0;
            app.mergerowsbyCheckBox.Visible = "off";

            app.variableDropDown.Visible = "off";
            app.variableDropDownLabel.Visible = "off";

            % hide the break all button
            app.breakButton.Visible = "off";

            % hide the plot panel until a plot is shown
            app.LeftPanel_3.Visible = "off";

            % hide the dropdowns until files are loaded
            app.categoryDropDown.Visible = "off";
            app.categoryLabel.Visible = "off";

            app.colorDropDown.Visible = "off";
            app.colorDropDownLabel.Visible = "off";

            app.shapeDropDown.Visible = "off";
            app.shapeDropDownLabel.Visible = "off";

            set(app.ImagescaleEditField,'Editable','off')
            set(app.ImagescaleEditField,'Visible','off')

            app.app_data.defaults.num_harmonics_to_export = 8;

        end

        % Button pushed function: setfolderButton
        function setfolderButtonPushed(app, event)
            message='set working folder';
            update_board(app,message);

            folder_type = 'working_folder';
            app.app_data.folder_type = folder_type;

            [app] = select_working_folder(app);
            update_board(app,message);

            message='working folder selectvariableDropDowned:';
            update_board(app,message);

            user_dir_path = app.app_data.user_dir_path;

            message=user_dir_path;
            update_board(app,message);

            % update the sample name based on the folder
            sample_name = app.app_data.user_dir_path_only_folder;
            message=sample_name;
            update_board(app,message);

            app.app_data.sample_name = sample_name;
            app.SamplenameEditField.Value =  app.app_data.sample_name;

            message='working folder selected2';
            update_board(app,message);

            working_folder_path = fullfile(user_dir_path);
            %    addpath(working_folder_path);
            % change folder
            cd(working_folder_path);

            message=working_folder_path;
            update_board(app,message);

            message=pwd();
            update_board(app,message);

            message = strcat('sample name changed:', sample_name);
            update_board(app,message);

        end

        % Value changed function: SamplenameEditField
        function SamplenameEditFieldValueChanged(app, event)
            % change the sample name when the user changes it

            sample_name = app.SamplenameEditField.Value;
            app.app_data.sample_name = sample_name;
            message=strcat('sample name changed:', sample_name);
            update_board(app,message);
        end

        % Button pushed function: readimagesButton
        function readimagesButtonPushed(app, event)
            % before reading the file check which formats are required
            1+1
            image_file_format = app.imagetypesListBox.Value;
            app.app_data.image_file_format = image_file_format;

            message = strcat('image format changed:', image_file_format);
            update_board(app,message);

            user_dir_path = app.app_data.user_dir_path;
            image_file_format = app.app_data.image_file_format;

            format_to_read = strcat('*.',image_file_format);
            format_to_read = format_to_read{1};

            file_list = dir(fullfile(user_dir_path, '**', format_to_read));
            % file_list_cell = struct2cell(file_list);

            n_image_files = 0;

            try
                file_list_var = extractfield(file_list,"name");
                n_image_files = length(file_list_var);
            end

            if n_image_files > 0
                % n_image_files = length(file_list);    % Number of files found
                image_files_list_cell = extractfield(file_list,'name')';
                image_files_order = num2cell([1:length(image_files_list_cell)]');
                is_analyzed_indication = num2cell(zeros(length(image_files_list_cell),1));


                % sort the table based on the file name
                % currently not working
                %     image_files_order_table = sort(image_files_list_cell, 'ComparisonMethod', 'abs');

                image_files_order_table = table(image_files_order,image_files_list_cell,is_analyzed_indication, 'VariableNames',["order","file_name",'is_analyzed']);

                % get the required information
                app.app_data.n_image_files = n_image_files;
                app.app_data.image_files_order = image_files_order;
                app.app_data.image_files_order_table = image_files_order_table;
                app.app_data.image_files_list_cell = image_files_list_cell;

                message=strcat(num2str(n_image_files),' images found');
                update_board(app,message);

                % update list of files found in the folder
                %       app.ImageListUITable.ColumnName = app.app_data.image_files_order_table.Properties.VariableNames;
                app.ImageListUITable.Data = app.app_data.image_files_order_table;
                app.CenterPanel.Visible = "on";

                % reset the rows color
                [app] = update_UI_table_color(app)

            else
                % no images matching the required format were found
                message=strcat('no images found in the folder. check image format');
                update_board(app,message);

            end

            %try
            % if no images were found, no automatic idetifcation of the
            % file name can be made
            if isempty(app.fileformatEditField.Value)
                app.fileformatEditField.Value = app.app_data.image_files_list_cell{1};
                file_name_format = app.fileformatEditField.Value;
                app.app_data.file_name_format = file_name_format;

                [app] = update_file_format(app);
            end
            %end

        end

        % Value changed function: imagetypesListBox
        function imagetypesListBoxValueChanged(app, event)
            image_file_format = app.imagetypesListBox.Value;
            app.app_data.image_file_format = image_file_format;

            message = strcat('image format changed:', image_file_format);
            update_board(app,message);
        end

        % Button pushed function: analyzeimagesButton
        function analyzeimagesButtonPushed(app, event)
            message = strcat('initializing images analyses');
            update_board(app,message);

            [app] = create_folder(app);
            message = strcat('folder created');
            update_board(app,message)

            1+1

            [app] = preallocate_variables(app);
            message = strcat('variables allocated');
            update_board(app,message)

            % prevent adjusting the file format string
            set(app.fileformatEditField, "Editable",'off')
            [app] = analyze_images(app);

            %   set(app.ApproveanalysesButtonGroup, "Visibility",'off')

        end

        % Value changed function: scaleisSwitch
        function scaleisSwitchValueChanged(app, event)
            scale_known_value = app.scaleisSwitch.Value;

            % the scale is known
            switch scale_known_value

                case 'known' % the scale is known
                    % set the field for editing
                    set(app.ImagescaleEditField,'Visible','on')
                    set(app.ImagescaleEditField,'Editable','on')

                    % update the scale
                    % save the scale into the structure
                    scale_pix_2_cm = app.ImagescaleEditField.Value;
                    app.app_data.scale_pix_2_cm = scale_pix_2_cm;
                    app.app_data.scale_known_value = scale_known_value;
                    app.app_data.scale_known_ind = 1;

                    message = strcat('image scale is set to:',num2str(scale_pix_2_cm));
                    update_board(app,message);

                    if scale_pix_2_cm==0
                        warndlg('scale is set to zero!')
                    end

                case 'unknown' % the scale is unknown
                    % remove the field from editing
                    set(app.ImagescaleEditField,'Value',0)
                    set(app.ImagescaleEditField,'Editable','off')
                    set(app.ImagescaleEditField,'Visible','off')

                    app.app_data.scale_known_ind = 0;

                    message = strcat('image scale is unknown');
                    update_board(app,message);
            end

        end

        % Value changed function: ImagescaleEditField
        function ImagescaleEditFieldValueChanged(app, event)
            % update the scale
            % save the scale into the structure
            scale_pix_2_cm = app.ImagescaleEditField.Value;
            app.app_data.scale_pix_2_cm = scale_pix_2_cm;

            message = strcat('image scale is set to:',num2str(scale_pix_2_cm));
            update_board(app,message);

        end

        % Value changed function: nclustersSpinner
        function nclustersSpinnerValueChanged(app, event)
            n_clusters = app.nclustersSpinner.Value;
            app.app_data.n_clusters = n_clusters;

        end

        % Value changed function: fileformatEditField
        function fileformatEditFieldValueChanged(app, event)
            file_name_format = app.fileformatEditField.Value;
            app.app_data.file_name_format = file_name_format;

            [app] = update_file_format(app);
        end

        % Selection changed function: ApproveanalysesButtonGroup
        function ApproveanalysesButtonGroupSelectionChanged(app, event)
            selectedButton_approved = app.ApproveanalysesButtonGroup.SelectedObject;
            selectedButton_approved_text = selectedButton_approved.Text;

            app.app_data.approved_button = selectedButton_approved_text;

            switch selectedButton_approved_text

                case 'not approved'
                    approved_status = 0;

                case 'approved'
                    approved_status = 1;

                case 'unknown'
                    approved_status = NaN;

            end

            app.app_data.approved_status = approved_status;

            % update the row colors
            [app] = update_UI_table_color(app)

        end

        % Callback function: Hyperlink
        function HyperlinkClicked(app, event)
            web("https://creativecommons.org/licenses/by-nc-sa/4.0/")
        end

        % Button pushed function: EladBenDorButton
        function EladBenDorButtonPushed(app, event)
            web("https://www.researchgate.net/profile/Elad-Ben-Dor-2")
        end

        % Button pushed function: YoavBenDorButton
        function YoavBenDorButtonPushed(app, event)
            web("https://www.researchgate.net/profile/Yoav-Ben-Dor")
        end

        % Button pushed function: setfolderButton_2
        function setfolderButton_2Pushed(app, event)
            message='set references data folder';
            update_board(app,message);

            folder_type = 'references_folder';
            app.app_data.folder_type = folder_type;

            [app] = select_working_folder(app);
            update_board(app,message);

            message='references folder selected:';
            update_board(app,message);

            user_data_references_dir_path = app.app_data.user_data_references_dir_path;

            message=user_data_references_dir_path;
            update_board(app,message);

            message='references data folder selected';
            update_board(app,message);

            references_data_folder_path = fullfile(user_data_references_dir_path);
            %    addpath(working_folder_path);
            % change folder
            cd(references_data_folder_path);

            % make load csvs button available
            app.loadCSVsButton.Visible = "on";

            % make merge rows checkbox visible
            app.mergerowsbyCheckBox.Visible = "on";

            % by default, check the merge rows checkbox
            app.mergerowsbyCheckBox.Value = 0;

            % update the default merge variable
            check_value = app.mergerowsbyCheckBox.Value;
            app.app_data.merge_rows = check_value;

            % if the user checked the box, make the dropdown appear
            if check_value
                app.variableDropDown.Visible = "on";
                app.variableDropDownLabel.Visible = "on";
                [app] = update_merge_variable(app);

            else
                app.variableDropDown.Visible = "off";
                app.variableDropDownLabel.Visible = "off";

            end

            % make merge rows variable list visible
            % app.variableDropDown.Visible = "on";

            message=references_data_folder_path;
            update_board(app,message);

            message=pwd();
            update_board(app,message);
        end

        % Button pushed function: loadCSVsButton
        function loadCSVsButtonPushed(app, event)
            %% load the csv files in the folders
            % check which folders were selected
            1+1
            if isfield(app.app_data,'user_data_references_dir_path')
                references_folder_exists = 1;
            else
                references_folder_exists = 0;
            end

            if isfield(app.app_data,'user_data_targets_dir_path')
                targets_folder_exists = 1;
            else
                targets_folder_exists = 0;
            end

            %% notify the user
            % the references folder exists
            if references_folder_exists
                % both the references and targets folders exist
                if targets_folder_exists
                    message = 'both references and targets folders set';

                else
                    % no targets folder exists
                    message = 'only references folder set';

                end

            elseif targets_folder_exists
                % only the targets folder exists
                message = 'only targets folder set';

            else
                % no folders were selected
                message = 'no data folders were set';

            end
            update_board(app,message)
            %% load the CSVs in the folders

            if references_folder_exists
                % load the references folder
                folder_type = 'references_folder';
                app.app_data.folder_type = folder_type;
                update_list_name = 'update_references';
                app.app_data.update_list_name = update_list_name;

                [app] = load_csvs_in_folder(app);

                % make relevant buttons available
                app.ReferencesListBox.Visible = "on";
                app.referencesListBoxLabel.Visible = "on";

                app.FeaturesListBox.Visible = "on";
                app.FeaturesListBoxLabel.Visible = "on";

                app.AnalysesKnob.Visible = "on";
                app.AnalysesKnobLabel.Visible = "on";

                app.selectallButton_2.Visible = "on";
                app.clearselectionButton.Visible = "on";
                app.savemergeddataButton.Visible = "on";

                message = [strjoin({num2str(length(app.app_data.loaded_data_tables_cm_ordered_references)), 'csv references files were loaded'},' ')];
                update_board(app,message)

                % update the relevant list box
                [app] = update_list_box(app);

                % select all items from the list box
                app.ReferencesListBox.Multiselect = 'on';
                ItemsData = app.ReferencesListBox.Items;
                app.ReferencesListBox.Value = ItemsData;

                app.categoryDropDown.Visible = "on";
                app.categoryLabel.Visible = "on";

                app.colorDropDown.Visible = "on";
                app.colorDropDownLabel.Visible = "on";

                % app.shapeDropDown.Visible = "on";
                % app.shapeDropDownLabel.Visible = "on";

            end

            if targets_folder_exists
                % load the targets folder
                folder_type = 'targets_folder';
                app.app_data.folder_type = folder_type;
                update_list_name = 'update_targets';
                app.app_data.update_list_name = update_list_name;

                [app] = load_csvs_in_folder(app);

                % make relevant buttons available
                app.TargetsListBox.Visible = "on";
                app.targetsListBoxLabel.Visible = "on";

                app.FeaturesListBox.Visible = "on";
                app.FeaturesListBoxLabel.Visible = "on";

                app.AnalysesKnob.Visible = "on";
                app.AnalysesKnobLabel.Visible = "on";

                app.clearselectionButton_2.Visible = "on";

                app.selectallButton_3.Visible = "on";

                app.savemergeddataButton.Visible = "on";

                message = [strjoin({num2str(length(app.app_data.loaded_data_tables_cm_ordered_targets)), 'csv target files were loaded'},' ')];
                update_board(app,message)

                % update the relevant list box
                [app] = update_list_box(app);

                % select all items from the list box
                app.TargetsListBox.Multiselect = 'on';
                ItemsData = app.TargetsListBox.Items;
                app.TargetsListBox.Value = ItemsData;

                app.categoryDropDown.Visible = "on";
                app.categoryLabel.Visible = "on";

                app.colorDropDown.Visible = "on";
                app.colorDropDownLabel.Visible = "on";

                % app.shapeDropDown.Visible = "on";
                % app.shapeDropDownLabel.Visible = "on";

            end

            % update the default plotting columns
            % set the default colomns for the plot
            app.app_data.color_column_ind = 1;
            app.app_data.category_column_ind = 1;
            app.app_data.shape_column_ind = 1;

            % update the features list
            if targets_folder_exists || references_folder_exists
                update_list_name = 'update_features';
                app.app_data.update_list_name = update_list_name;
                [app] = update_list_box(app);

                % update also the dropdown menus with the metadata columns
                [app] = update_dropdown(app);

                % make the exportall button available
                app.exportallfiguresButton.Visible = "on";
                app.plotButton.Visible = "on";
            end

            % merge all the tables into a single table
            [app] = merge_all_tables(app);


        end

        % Button pushed function: setfolderButton_3
        function setfolderButton_3Pushed(app, event)
            message='set targets data folder';
            update_board(app,message);

            folder_type = 'targets_folder';
            app.app_data.folder_type = folder_type;

            [app] = select_working_folder(app);
            update_board(app,message);

            message='targets folder selected:';
            update_board(app,message);

            user_data_targets_dir_path = app.app_data.user_data_targets_dir_path;

            message=user_data_targets_dir_path;
            update_board(app,message);

            message='targets data folder selected';
            update_board(app,message);

            targets_data_folder_path = fullfile(user_data_targets_dir_path);
            %    addpath(working_folder_path);
            % change folder
            cd(targets_data_folder_path);

            % make load csvs button available
            app.loadCSVsButton.Visible = "on";

            % make merge rows checkbox visible
            app.mergerowsbyCheckBox.Visible = "on";

            % make merge rows variable list visible
            % app.variableDropDown.Visible = "on";

            message=targets_data_folder_path;
            update_board(app,message);

            message=pwd();
            update_board(app,message);
        end

        % Button pushed function: selectallButton_2
        function selectallButton_2Pushed(app, event)
            % select all items from the list box
            app.ReferencesListBox.Multiselect = 'on';
            ItemsData = app.ReferencesListBox.Items;
            app.ReferencesListBox.Value = ItemsData;
        end

        % Button pushed function: selectallButton_3
        function selectallButton_3Pushed(app, event)
            % select all items from the list box
            app.TargetsListBox.Multiselect = 'on';
            ItemsData = app.TargetsListBox.Items;
            app.TargetsListBox.Value = ItemsData;
        end

        % Callback function
        function selectallButtonPushed(app, event)
            % select all items from the list box
            app.FeaturesListBox.Multiselect = 'on';
            ItemsData = app.FeaturesListBox.Items;
            app.FeaturesListBox.Value = ItemsData;
        end

        % Button pushed function: savemergeddataButton
        function savemergeddataButtonPushed(app, event)
            save_data_selected_dir = uigetdir(); % ask user for data directory
            app.app_data.save_data_selected_dir = save_data_selected_dir;

            if isfield(app.app_data,'loaded_data_cm_ordered_references_all')
                try
                    filename_references = fullfile(app.app_data.save_data_selected_dir,'merged_references_cm.csv');
                    writetable(app.app_data.loaded_data_cm_ordered_references_all,filename_references)
                catch
                    message = 'error saving merged references file';
                    update_board(app,message);
                    msgbox(message,"Error","error");
                    error(message);
                    stop;
                end
            else
                message = 'merged references table not found';
                update_board(app,message);
                msgbox(message,"Error","warn");
            end


            if isfield(app.app_data,'loaded_data_cm_ordered_targets_all')
                try
                    filename_targets = fullfile(app.app_data.save_data_selected_dir,'merged_targets_cm.csv');
                    writetable(app.app_data.loaded_data_cm_ordered_targets_all,filename_targets)
                catch
                    message = 'error saving merged targets file';
                    update_board(app,message);
                    msgbox(message,"Error","error");
                    error(message);
                    stop;
                end
            else
                message = 'merged targets table not found';
                update_board(app,message);
                msgbox(message,"Error","warn");
            end
        end

        % Button pushed function: plotButton
        function plotButtonPushed(app, event)
            %


            % hide the plot panel until a plot is shown
            app.LeftPanel_3.Visible = "on";

            plot_type = app.AnalysesKnob.Value;
            app.app_data.plot_type = plot_type;

            app.app_data.export_all_break = 0;

            % prepare the data (extract rows and cols)
            [app] = prepare_data_to_plot(app);

            y_to_plot = app.app_data.y_to_plot;
            groups_to_plot = app.app_data.groups_to_plot;
            col_to_plot = app.app_data.col_to_plot;

            % types_to_plot = app.app_data.types_to_plot;
            % sorted_t = app.app_data.sorted_t;
            sorted_t = app.app_data.sorted_t;
            unique_groups_to_plot = app.app_data.unique_groups_to_plot;

            for kk = 1:length(unique_groups_to_plot)
                [unique_groups_to_plot_vec(kk)] = find(strcmp(unique_groups_to_plot{kk},sorted_t(:,2)),1,'first');
            end

            [unique_groups_to_plot_table] = sorted_t(unique_groups_to_plot_vec,:);

            cla(app.UIAxes, 'reset')
            current_axes = app.UIAxes;

            switch plot_type

                case 'Boxplots'

                    boxplot(current_axes, y_to_plot,groups_to_plot)
                    title(current_axes,col_to_plot, 'Interpreter','none');
                    xlabel(current_axes,'sample');
                    ylabel(current_axes,col_to_plot, 'Interpreter','none');

                case 'CDF'

                    for ii = 1:length(unique_groups_to_plot)
                        current_data_to_cdf = y_to_plot(strcmp(unique_groups_to_plot(ii),groups_to_plot));

                        % cahnge linestyle according to sample type
                        if strcmp(unique_groups_to_plot_table{find(strcmp(unique_groups_to_plot(ii),unique_groups_to_plot_table(:,2)),1)},'reference')
                            current_line_style = '-';
                        elseif strcmp(unique_groups_to_plot_table{find(strcmp(unique_groups_to_plot(ii),unique_groups_to_plot_table(:,2)),1)},'target')
                            current_line_style = '--';
                        end

                        [f,x] = ecdf(current_data_to_cdf);

                        plot(current_axes,x,f,'LineStyle',current_line_style);
                        hold(current_axes, 'on' )

                        title(current_axes,col_to_plot, 'Interpreter','none');
                        ylabel(current_axes,'F(x)')
                        xlabel(current_axes,col_to_plot, 'Interpreter','none');

                    end
                    legend(current_axes,unique_groups_to_plot,'Location','best')

                case 'PDE'

                    for ii = 1:length(unique_groups_to_plot)
                        current_data_to_cdf = y_to_plot(strcmp(unique_groups_to_plot(ii),groups_to_plot));
                        % cahnge linestyle according to sample type
                        if strcmp(unique_groups_to_plot_table{find(strcmp(unique_groups_to_plot(ii),unique_groups_to_plot_table(:,2)),1)},'reference')
                            current_line_style = '-';
                        elseif strcmp(unique_groups_to_plot_table{find(strcmp(unique_groups_to_plot(ii),unique_groups_to_plot_table(:,2)),1)},'target')
                            current_line_style = '--';
                        end


                        [f,x] = ksdensity(current_data_to_cdf);

                        plot(current_axes,x,f,'LineStyle',current_line_style);
                        hold(current_axes, 'on' )

                        title(current_axes,col_to_plot, 'Interpreter','none');
                        ylabel(current_axes,'P(x)')
                        xlabel(current_axes,col_to_plot, 'Interpreter','none');

                    end
                    legend(current_axes,unique_groups_to_plot,'Location','best')


                case 'PCA'
                    %
                    % app.PC1DropDown.Visible = "on";
                    % app.PC1DropDownLabel.Visible = "on";
                    %
                    % app.PC2DropDown.Visible = "on";
                    % app.PC2DropDownLabel.Visible = "on";

                    msgbox('PCA is calculated using all features and selected samples');

                    % calculate the PCA
                    [app] = calculate_pca(app);

                    score = app.app_data.pca.score;
                    explained = app.app_data.pca.explained;
                    newPointProjected = app.app_data.pca.newPointProjected;

                    Groups_references =  app.app_data.pca.Groups_references;
                    ID_references = app.app_data.pca.ID_references;

                    Groups_targets = app.app_data.pca.Groups_targets;
                    ID_targets = app.app_data.pca.ID_targets;

                    % update the dropdown menus of the PC1
                    app.PC1DropDown.Items = cellfun(@num2str,num2cell(1:length(explained)),'UniformOutput',false);
                    app.PC2DropDown.Items = cellfun(@num2str,num2cell(1:length(explained)),'UniformOutput',false);

                    if ~(isfield(app.app_data, "PC1"))
                        app.PC1DropDown.ValueIndex = 1;
                        app.app_data.PC1 = app.PC1DropDown.ValueIndex;

                        app.PC2DropDown.ValueIndex = 2;
                        app.app_data.PC2 = app.PC2DropDown.ValueIndex;

                    end

                    % test if the calculation really brings the same values
                    % https://www.mathworks.com/matlabcentral/answers/796667-scaling-new-data-to-preoject-them-onto-my-pca-space
                    % coeff = coeff(:, 1:2); % Keep the first two principal components
                    % stddev = std(references_to_pca_data_norm); % Calculate standard deviation of the original dataset
                    % newPointStandardized = (references_to_pca_data_norm - mu) ./ stddev; % Standardize the new point
                    % newPointProjected = newPointStandardized * coeff; % Project the new point onto the PCA axes

                    % get the PCs to plot
                    PC1_to_plot = app.app_data.PC1;
                    PC2_to_plot = app.app_data.PC2;

                    % plot the data
                    % plot references
                    gscatter(current_axes,score(:,PC1_to_plot), score(:,PC2_to_plot), Groups_references,lines(height(ID_references)),'o',5)
                    hold(current_axes, 'on' )

                    % plot the targets
                    gscatter(current_axes,newPointProjected(:,PC1_to_plot), newPointProjected(:,PC2_to_plot),Groups_targets,lines(height(ID_targets)), '+',5)

                    title(current_axes,'PCA', 'Interpreter','none');
                    xlabel(current_axes,strjoin({strcat(strcat('PC',num2str(PC1_to_plot)),':') ,strcat(num2str(explained(PC1_to_plot),'%4.2f'),'%'),'explained variance'},' '), 'Interpreter','none')
                    ylabel(current_axes,strjoin({strcat(strcat('PC',num2str(PC2_to_plot)),':') ,strcat(num2str(explained(PC2_to_plot),'%4.2f'),'%'),'explained variance'},' '), 'Interpreter','none')

                    legend(current_axes,table2array(vertcat(ID_references,ID_targets)),'Location','best')

            end

        end

        % Button pushed function: clearselectionButton
        function clearselectionButtonPushed(app, event)
            % unselect all items from the list box
            app.ReferencesListBox.ValueIndex = [];
        end

        % Button pushed function: clearselectionButton_2
        function clearselectionButton_2Pushed(app, event)
            % unselect all items from the list box
            app.TargetsListBox.ValueIndex = [];
        end

        % Button pushed function: exportallfiguresButton
        function exportallfiguresButtonPushed(app, event)
            %% notify user
            message = 'exporting all figures will take a while';
            update_board(app,message);
            tmp_msgbox = msgbox(message);
            uiwait(tmp_msgbox);

            app.app_data.export_all_break = 0;

            app.LeftPanel_3.Visible = "on";
            app.breakButton.Visible = "on";

            user_decision = questdlg('Proceed with export all?', ...
                'user confirmation', ...
                'Yes','No','Yes');

            switch user_decision
                case 'Yes'
                    %'OK' code here
                    proceed_with_export = 1;
                case 'No'
                    proceed_with_export = 0;
                    %abort code
                otherwise
            end

            if proceed_with_export
                %% prepare for plotting
                plot_type = app.AnalysesKnob.Value;
                app.app_data.plot_type = plot_type;

                % prepare the data (extract rows and cols)
                [app] = prepare_data_to_plot(app);

                all_data_to_plot_only_data = app.app_data.all_data_to_plot_only_data;
                groups_to_plot = app.app_data.groups_to_plot;
                rows_to_plot = app.app_data.rows_to_plot;

                % types_to_plot = app.app_data.types_to_plot;
                sorted_t = app.app_data.sorted_t;
                unique_groups_to_plot = app.app_data.unique_groups_to_plot;

                for kk = 1:length(unique_groups_to_plot)
                    [unique_groups_to_plot_vec(kk)] = find(strcmp(unique_groups_to_plot{kk},sorted_t(:,2)),1,'first');
                end

                [unique_groups_to_plot_table] = sorted_t(unique_groups_to_plot_vec,:);


                %% get user folder
                message = 'select export folder';
                update_board(app,message);
                tmp_msgbox = msgbox(message);
                uiwait(tmp_msgbox);

                [app] = select_export_folder(app);
                [app] = create_export_all_folder(app);


                %% prepare for plotting
                % reset the window
                cla(app.UIAxes, 'reset')
                current_axes = app.UIAxes;

                %% plot and export
                1+1
                if ismember(plot_type,{'Boxplots','CDF','PDE'})
                    for jj = 1:size(all_data_to_plot_only_data,2)
                        % check if the uesr clicked on break
                        pause(0.2)
                        break_opeation = app.app_data.export_all_break;
                        pause(0.2)

                        % do not break the operation
                        if ~break_opeation

                            % get the data
                            col_to_plot = app.FeaturesListBox.Items{jj};
                            app.FeaturesListBox.Value = col_to_plot;
                            app.app_data.col_to_plot = col_to_plot;
                            y_to_plot = table2array(all_data_to_plot_only_data(rows_to_plot,col_to_plot));

                            % reset the window
                            cla(app.UIAxes, 'reset')
                            current_axes = app.UIAxes;

                            switch plot_type

                                case 'Boxplots'

                                    boxplot(current_axes, y_to_plot,groups_to_plot)
                                    title(current_axes,col_to_plot, 'Interpreter','none');
                                    xlabel(current_axes,'sample');
                                    ylabel(current_axes,col_to_plot, 'Interpreter','none');

                                case 'CDF'

                                    for ii = 1:length(unique_groups_to_plot)
                                        current_data_to_cdf = y_to_plot(strcmp(unique_groups_to_plot(ii),groups_to_plot));

                                        % cahnge linestyle according to sample type
                                        if strcmp(unique_groups_to_plot_table{find(strcmp(unique_groups_to_plot(ii),unique_groups_to_plot_table(:,2)),1)},'reference')
                                            current_line_style = '-';
                                        elseif strcmp(unique_groups_to_plot_table{find(strcmp(unique_groups_to_plot(ii),unique_groups_to_plot_table(:,2)),1)},'target')
                                            current_line_style = '--';
                                        end

                                        [f,x] = ecdf(current_data_to_cdf);
                                        plot(current_axes,x,f,'LineStyle',current_line_style);
                                        hold(current_axes, 'on' )

                                        title(current_axes,col_to_plot, 'Interpreter','none');
                                        ylabel(current_axes,'F(x)')
                                        xlabel(current_axes,col_to_plot, 'Interpreter','none');

                                    end
                                    legend(current_axes,unique_groups_to_plot,'Location','best')

                                case 'PDE'

                                    for ii = 1:length(unique_groups_to_plot)
                                        current_data_to_cdf = y_to_plot(strcmp(unique_groups_to_plot(ii),groups_to_plot));

                                        % cahnge linestyle according to sample type
                                        if strcmp(unique_groups_to_plot_table{find(strcmp(unique_groups_to_plot(ii),unique_groups_to_plot_table(:,2)),1)},'reference')
                                            current_line_style = '-';
                                        elseif strcmp(unique_groups_to_plot_table{find(strcmp(unique_groups_to_plot(ii),unique_groups_to_plot_table(:,2)),1)},'target')
                                            current_line_style = '--';
                                        end

                                        [f,x] = ksdensity(current_data_to_cdf);
                                        plot(current_axes,x,f,'LineStyle',current_line_style);
                                        hold(current_axes, 'on' )

                                        title(current_axes,col_to_plot, 'Interpreter','none');
                                        ylabel(current_axes,'P(x)')
                                        xlabel(current_axes,col_to_plot, 'Interpreter','none');

                                    end
                                    legend(current_axes,unique_groups_to_plot,'Location','best')

                            end
                            [app] = export_figure(app);
                        end
                    end

                    % if the user wants to export the PCA
                elseif ismember(plot_type,{'PCA'})
                    %% plot the PCA plots

                    message = 'PCA is calculated using all features and selected samples';
                    update_board(app,message);
                    msgbox(message);

                    % calculate the PCA
                    [app] = calculate_pca(app);

                    coeff = app.app_data.pca.coeff;
                    score = app.app_data.pca.score;
                    explained = app.app_data.pca.explained;
                    newPointProjected = app.app_data.pca.newPointProjected;

                    Groups_references =  app.app_data.pca.Groups_references;
                    ID_references = app.app_data.pca.ID_references;

                    Groups_targets = app.app_data.pca.Groups_targets;
                    ID_targets = app.app_data.pca.ID_targets;

                    % update the dropdown menus of the PC1
                    app.PC1DropDown.Items = cellfun(@num2str,num2cell(1:length(explained)),'UniformOutput',false);
                    app.PC2DropDown.Items = cellfun(@num2str,num2cell(1:length(explained)),'UniformOutput',false);

                    if ~(isfield(app.app_data, "PC1"))
                        app.PC1DropDown.ValueIndex = 1;
                        app.app_data.PC1 = app.PC1DropDown.ValueIndex;

                        app.PC2DropDown.ValueIndex = 2;
                        app.app_data.PC2 = app.PC2DropDown.ValueIndex;
                    end

                    %% check for cancellation
                    % check if the uesr clicked on break
                    pause(0.2)
                    break_opeation = app.app_data.export_all_break;
                    pause(0.2)

                    if ~break_opeation
                        %% export the PCA table
                        n_PCs = size(score,2);
                        PC_vec = strcat(repmat('PC',[n_PCs,1]),cellfun(@num2str, num2cell(1:n_PCs), 'UniformOutput', false)');
                        PC_score_vec = strcat(repmat('score_',[n_PCs,1]),PC_vec);

                        % if targets exist as well, add them to the table
                        if isempty(newPointProjected)
                            % no targets
                            score_table = array2table(score);
                        else
                            % targets exist
                            score_table = array2table(vertcat(score,newPointProjected));
                        end

                        score_table.Properties.VariableNames = PC_score_vec;
                        descriptors_table = vertcat(app.app_data.pca.references_to_pca_descriptors, app.app_data.pca.targets_to_pca_descriptors);
                        table_to_export = horzcat(descriptors_table,score_table);

                        filename_pca = fullfile(app.app_data.current_save_all_folder,'PCA_scores.csv');
                        writetable(table_to_export,filename_pca);

                        % export the explained variance
                        explained_table = array2table([PC_vec,num2cell(explained)]);
                        explained_table.Properties.VariableNames = {'PC','explained_variance'};

                        filename_pca = fullfile(app.app_data.current_save_all_folder,'PCA_explained_variance.csv');
                        writetable(explained_table,filename_pca);

                        % export the coefficients
                        pca_variables = app.app_data.all_data_to_plot_only_data.Properties.VariableNames';

                        coeff_table = array2table([pca_variables,num2cell(coeff)]);
                        coeff_table.Properties.VariableNames = [{'feature'},PC_vec'];

                        filename_pca = fullfile(app.app_data.current_save_all_folder,'PCA_coefficients.csv');
                        writetable(coeff_table,filename_pca);
                    end


                    %% check for cancellation
                    % check if the uesr clicked on break
                    pause(0.2)
                    break_opeation = app.app_data.export_all_break;
                    pause(0.2)

                    if ~break_opeation
                        %% plot the explained variance plot
                        pca_plot_type = 'explained';
                        app.app_data.pca_plot_type = pca_plot_type;

                        pca_plot_ind = 1;
                        app.app_data.pca_plot_ind =  pca_plot_ind;

                        % reset the window
                        cla(app.UIAxes, 'reset')
                        current_axes = app.UIAxes;

                        % plot
                        plot(current_axes,[1:length(explained)], explained,'bo-');
                        xlim(current_axes,[1 inf]);
                        xlabel(current_axes,'PC');
                        ylabel(current_axes,'% explained variance');
                        title(current_axes,'% explained variance');

                        % export
                        [app] = export_figure(app);

                        %% plot only the first 10 PCs
                        pca_plot_ind = pca_plot_ind + 1;
                        app.app_data.pca_plot_ind =  pca_plot_ind;

                        % reset the window
                        cla(app.UIAxes, 'reset')
                        current_axes = app.UIAxes;

                        % plot
                        plot(current_axes,[1:min(length(explained),10)], explained([1:min(length(explained),10)]),'bo-');
                        xlim(current_axes,[1 inf]);
                        xlabel(current_axes,'PC');
                        ylabel(current_axes,'% explained variance');
                        title(current_axes,'% explained variance');

                        % export
                        [app] = export_figure(app);

                        %% plot only the cumsum all PCs
                        pca_plot_ind = pca_plot_ind + 1;
                        app.app_data.pca_plot_ind =  pca_plot_ind;

                        % reset the window
                        cla(app.UIAxes, 'reset')
                        current_axes = app.UIAxes;

                        % plot
                        plot(current_axes,[1:length(explained)], cumsum(explained),'bo-');
                        xlim(current_axes,[1 inf]);
                        xlabel(current_axes,'PC')
                        ylabel(current_axes,'cumulative % explained variance')
                        title(current_axes,'cumulative % explained variance')

                        % export
                        [app] = export_figure(app);

                        %% plot cumulative only the first 10 PCs
                        pca_plot_ind = pca_plot_ind + 1;
                        app.app_data.pca_plot_ind =  pca_plot_ind;

                        % reset the window
                        cla(app.UIAxes, 'reset')
                        current_axes = app.UIAxes;

                        % plot
                        plot(current_axes,[1:min(length(explained),10)], cumsum(explained([1:min(length(explained),10)])),'bo-');
                        xlim(current_axes,[1 inf]);
                        xlabel(current_axes,'PC');
                        ylabel(current_axes,'cumulative % explained variance');
                        title(current_axes,'cumulative % explained variance');

                        % export
                        [app] = export_figure(app);
                    end

                    %% export the indplot figures
                    % get the PCs to plot
                    % adjust here to aacount for when not enough PCs were
                    % calculated

                    % adjust the maximum number of PCs to be plotted based
                    % on available PCA results
                    pc1_plot_vector = [1:(min(10,length(app.app_data.pca.explained))-1)];
                    pc2_plot_vector = pc1_plot_vector+1;

                    %% export ind_plots
                    pca_plot_type = 'indplot';
                    app.app_data.pca_plot_type = pca_plot_type;

                    pca_plot_ind = 1;
                    app.app_data.pca_plot_ind =  pca_plot_ind;

                    for kk=1:length(pc1_plot_vector)
                        %% check for cancellation
                        % check if the uesr clicked on break
                        pause(0.2)
                        break_opeation = app.app_data.export_all_break;
                        pause(0.2)

                        if ~break_opeation
                            try
                                app.app_data.pca_plot_ind =  pca_plot_ind;

                                PC1_to_plot = pc1_plot_vector(kk);
                                PC2_to_plot = pc2_plot_vector(kk);

                                % update the dropdown menus of the PC1
                                app.PC1DropDown.ValueIndex = PC1_to_plot;
                                app.PC2DropDown.ValueIndex = PC2_to_plot;

                                % reset the window
                                cla(app.UIAxes, 'reset')
                                current_axes = app.UIAxes;

                                % plot the data
                                gscatter(current_axes,score(:,PC1_to_plot), score(:,PC2_to_plot), Groups_references,lines(height(ID_references)),'o',5)
                                hold(current_axes, 'on' )
                                if ~isempty(newPointProjected)
                                    gscatter(current_axes,newPointProjected(:,PC1_to_plot), newPointProjected(:,PC2_to_plot),Groups_targets,lines(height(ID_targets)), '+',5)
                                end

                                % add title and lables
                                title(current_axes,'PCA', 'Interpreter','none');
                                xlabel(current_axes,strjoin({strcat(strcat('PC',num2str(PC1_to_plot)),':') ,strcat(num2str(explained(PC1_to_plot),'%4.2f'),'%'),'explained variance'},' '), 'Interpreter','none')
                                ylabel(current_axes,strjoin({strcat(strcat('PC',num2str(PC2_to_plot)),':') ,strcat(num2str(explained(PC2_to_plot),'%4.2f'),'%'),'explained variance'},' '), 'Interpreter','none')

                                % add legend
                                try
                                    legend(current_axes,table2array(vertcat(ID_references,ID_targets)),'Location','best')
                                catch
                                    legend(current_axes,table2array(vertcat(ID_references)),'Location','best')
                                end

                                % export
                                [app] = export_figure(app);

                                % update plot index
                                pca_plot_ind = pca_plot_ind + 1;
                            catch

                            end
                        end
                    end

                    %% export biplots
                    pca_plot_type = 'biplot';
                    app.app_data.pca_plot_type = pca_plot_type;

                    pca_plot_ind = 1;
                    app.app_data.pca_plot_ind =  pca_plot_ind;

                    for kk=1:length(pc1_plot_vector)
                        %% check for cancellation
                        % check if the uesr clicked on break
                        pause(0.2)
                        break_opeation = app.app_data.export_all_break;
                        pause(0.2)

                        if ~break_opeation
                            app.app_data.pca_plot_ind =  pca_plot_ind;

                            PC1_to_plot = pc1_plot_vector(kk);
                            PC2_to_plot = pc2_plot_vector(kk);

                            % update the dropdown menus of the PC1
                            app.PC1DropDown.ValueIndex = PC1_to_plot;
                            app.PC2DropDown.ValueIndex = PC2_to_plot;

                            % reset the window
                            cla(app.UIAxes, 'reset')
                            current_axes = app.UIAxes;

                            % plot the data
                            h = biplot(current_axes,coeff(:,[PC1_to_plot,PC2_to_plot]),'Scores', score(:,[PC1_to_plot,PC2_to_plot]),'VarLabels',pca_variables,'Marker','none'); %

                            % get the point values to scale the gscatter plots
                            % to match the biplot
                            h2 = findobj(h,"Tag","obsmarker");
                            XData_values = [h2.XData];
                            [min_PC1, max_PC1] = bounds(XData_values);
                            range_PC1 = [min_PC1, max_PC1] ;
                            hold(current_axes, 'on' )

                            % get the point values to scale the gscatter plots
                            % to match the biplot
                            h2 = findobj(h,"Tag","obsmarker");
                            YData_values = [h2.YData];
                            [min_PC2, max_PC2] = bounds(YData_values);
                            range_PC2 = [min_PC2, max_PC2] ;
                            hold(current_axes, 'on' )

                            %  range_PC1 = get(current_axes, 'XLim');
                            %   range_PC2 = get(current_axes, 'YLim');

                            %   [min_PC1, max_PC1] = bounds(coeff(:,[PC1_to_plot]));
                            %   range_PC1 = [min_PC1, max_PC1];

                            %   [min_PC2, max_PC2] = bounds(coeff(:,[PC2_to_plot]));
                            %   range_PC2 = [min_PC2, max_PC2];

                            % if isempty(newPointProjected)
                            1+1
                            % plot the references
                            p1 =  gscatter(current_axes,normalize(score(:,PC1_to_plot),"range",range_PC1), normalize(score(:,PC2_to_plot),"range",range_PC2), Groups_references,lines(height(ID_references)),'o',5);
                            %    legend(current_axes,[p1],table2array(vertcat(ID_references,ID_targets)),'Location','best')

                            %elseif ~isempty(newPointProjected)
                            % plot the targets
                            if ~isempty(newPointProjected)
                                p2 =  gscatter(current_axes,normalize(newPointProjected(:,PC1_to_plot),"range",range_PC1), normalize(newPointProjected(:,PC2_to_plot),"range",range_PC2), Groups_targets,lines(height(ID_targets)),'+',5);
                                %     legend(current_axes,[p1 p2],table2array(vertcat(ID_references,ID_targets)),'Location','best')
                                %   biplot(current_axes,newPointProjected(:,PC1_to_plot), newPointProjected(:,PC2_to_plot),Groups_targets,lines(height(ID_targets)), '+',5)
                            end

                            % add title and lables
                            title(current_axes,'PCA', 'Interpreter','none');
                            xlabel(current_axes,strjoin({strcat(strcat('PC',num2str(PC1_to_plot)),':') ,strcat(num2str(explained(PC1_to_plot),'%4.2f'),'%'),'explained variance'},' '), 'Interpreter','none')
                            ylabel(current_axes,strjoin({strcat(strcat('PC',num2str(PC2_to_plot)),':') ,strcat(num2str(explained(PC2_to_plot),'%4.2f'),'%'),'explained variance'},' '), 'Interpreter','none')

                            % add legend
                            % add title and lables
                            title(current_axes,'PCA', 'Interpreter','none');
                            xlabel(current_axes,strjoin({strcat(strcat('PC',num2str(PC1_to_plot)),':') ,strcat(num2str(explained(PC1_to_plot),'%4.2f'),'%'),'explained variance'},' '), 'Interpreter','none')
                            ylabel(current_axes,strjoin({strcat(strcat('PC',num2str(PC2_to_plot)),':') ,strcat(num2str(explained(PC2_to_plot),'%4.2f'),'%'),'explained variance'},' '), 'Interpreter','none')

                            % add legend
                            try
                                % both references and targets are present
                                legend(current_axes,[p1; p2],table2array(vertcat(ID_references,ID_targets)),'Location','best')
                            catch
                                % only references are present
                                legend(current_axes,[p1],table2array(vertcat(ID_references,ID_targets)),'Location','best')
                            end

                            % export
                            [app] = export_figure(app);

                            % update plot index
                            pca_plot_ind = pca_plot_ind + 1;
                        end
                    end

                end

            else
                message='export all cancelled';
                update_board(app,message);

            end

        end

        % Value changed function: PC1DropDown
        function PC1DropDownValueChanged(app, event)
            % the user selected the first PC to plot
            ValueIndex = app.PC1DropDown.ValueIndex;
            app.app_data.PC1 = ValueIndex;
        end

        % Value changed function: PC2DropDown
        function PC2DropDownValueChanged(app, event)

            % the user selected the second PC to plot
            ValueIndex = app.PC2DropDown.ValueIndex;
            app.app_data.PC2 = ValueIndex;

        end

        % Value changed function: AnalysesKnob
        function AnalysesKnobValueChanged(app, event)
            plot_type = app.AnalysesKnob.Value;

            switch plot_type
                case 'Boxplots'
                    app.PC1DropDown.Visible = "off";
                    app.PC1DropDownLabel.Visible = "off";

                    app.PC2DropDown.Visible = "off";
                    app.PC2DropDownLabel.Visible = "off";
                case 'CDF'
                    app.PC1DropDown.Visible = "off";
                    app.PC1DropDownLabel.Visible = "off";

                    app.PC2DropDown.Visible = "off";
                    app.PC2DropDownLabel.Visible = "off";
                case 'PDE'
                    app.PC1DropDown.Visible = "off";
                    app.PC1DropDownLabel.Visible = "off";

                    app.PC2DropDown.Visible = "off";
                    app.PC2DropDownLabel.Visible = "off";
                case 'PCA'
                    app.PC1DropDown.Visible = "on";
                    app.PC1DropDownLabel.Visible = "on";

                    app.PC2DropDown.Visible = "on";
                    app.PC2DropDownLabel.Visible = "on";

            end
        end

        % Callback function
        function widthEditFieldValueChanged(app, event)
            value = app.widthEditField.Value;
            app.app_data.figure_width = value;
        end

        % Callback function
        function heightEditFieldValueChanged(app, event)
            value = app.heightEditField.Value;
            app.app_data.figure_height = value;
        end

        % Button pushed function: breakButton
        function breakButtonPushed(app, event)
            app.app_data.export_all_break = 1;
        end

        % Value changed function: ReferencesListBox
        function ReferencesListBoxValueChanged(app, event)
            value = app.ReferencesListBox.Value;

        end

        % Value changed function: TargetsListBox
        function TargetsListBoxValueChanged(app, event)
            value = app.TargetsListBox.Value;

        end

        % Value changed function: mergerowsbyCheckBox
        function mergerowsbyCheckBoxValueChanged(app, event)
            check_value = app.mergerowsbyCheckBox.Value;
            app.app_data.merge_rows = check_value;

            % if the user checked the box, make the dropdown appear
            if check_value
                app.variableDropDown.Visible = "on";
                app.variableDropDownLabel.Visible = "on";
                [app] = update_merge_variable(app);

            else
                app.variableDropDown.Visible = "off";
                app.variableDropDownLabel.Visible = "off";

            end


        end

        % Value changed function: variableDropDown
        function variableDropDownValueChanged(app, event)
            %% get the value from the dropdown
            selected_merge_col = app.variableDropDown.Value;
            selected_merge_col_index = app.variableDropDown.ValueIndex;

            %% update the relevant variables
            app.app_data.selected_merge_col = selected_merge_col;
            app.app_data.selected_merge_col_index = selected_merge_col_index;

            %% notify user
            app.variableDropDownLabel.Text = selected_merge_col;
            message = strjoin({'organizing table by:', (app.app_data.selected_merge_col)},' ');
            update_board(app,message)
        end

        % Drop down opening function: variableDropDown
        function variableDropDownOpening(app, event)

        end

        % Size changed function: LeftPanel_3
        function LeftPanel_3SizeChanged(app, event)
            position = app.LeftPanel_3.Position;

        end

        % Value changed function: categoryDropDown
        function categoryDropDownValueChanged(app, event)
            % get the index of the category column for the plot
            value = app.categoryDropDown.Value;
            category_column = value;
            app.app_data.category_column = category_column;

            % get the index of the category column
            ind = app.categoryDropDown.ValueIndex;
            category_column_ind = ind;
            app.app_data.category_column_ind = category_column_ind;

            if isfield(app.app_data,"user_data_references_dir_path_only_folder")
                app.app_data.update_list_name = "update_references";
                [app] = update_list_box(app);
            end

            if isfield(app.app_data,"user_data_targets_dir_path_only_folder")
                app.app_data.update_list_name = "update_targets";
                [app] = update_list_box(app);
            end

            app.app_data.update_list_name = "update_features";
            [app] = update_list_box(app);
        end

        % Value changed function: colorDropDown
        function colorDropDownValueChanged(app, event)
            % get the value of the color column
            value = app.colorDropDown.Value;
            color_column = value;
            app.app_data.color_column = color_column;

            % get the index of the color column
            ind = app.colorDropDown.ValueIndex;
            color_column_ind = ind;
            app.app_data.color_column_ind = color_column_ind;

        end

        % Value changed function: shapeDropDown
        function shapeDropDownValueChanged(app, event)
            value = app.shapeDropDown.Value;
            shape_column = value;
            app.app_data.shape_column = shape_column;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ImageProcessingTab = uitab(app.TabGroup);
            app.ImageProcessingTab.Title = 'Image Processing';
            app.LeftPanel = uipanel(app.ImageProcessingTab);
            app.ReadimagesLabel = uilabel(app.LeftPanel);
            app.ReadimagesLabel.Text = 'Read images';
            app.readimagesButton = uibutton(app.LeftPanel, 'push');
            app.readimagesButton.ButtonPushedFcn = createCallbackFcn(app, @readimagesButtonPushed, true);
            app.readimagesButton.Text = 'read images';
            app.imagetypesListBoxLabel = uilabel(app.LeftPanel);
            app.imagetypesListBoxLabel.Text = 'image types';
            app.imagetypesListBox = uilistbox(app.LeftPanel);
            app.imagetypesListBox.Items = {'JPG', 'JPEG', 'PNG', 'BMP', 'TIF'};
            app.imagetypesListBox.Multiselect = 'on';
            app.imagetypesListBox.ValueChangedFcn = createCallbackFcn(app, @imagetypesListBoxValueChanged, true);
            app.imagetypesListBox.Value = {'JPG'};
            app.ImageListUITable = uitable(app.LeftPanel);
            app.ImageListUITable.ColumnName = {'order'; 'file name'; 'analyzed'};
            app.ImageListUITable.RowName = {};
            app.SamplenameEditFieldLabel = uilabel(app.LeftPanel);
            app.SamplenameEditFieldLabel.Text = 'Sample name:';
            app.SamplenameEditField = uieditfield(app.LeftPanel, 'text');
            app.SamplenameEditField.ValueChangedFcn = createCallbackFcn(app, @SamplenameEditFieldValueChanged, true);
            app.SetfolderLabel = uilabel(app.LeftPanel);
            app.SetfolderLabel.Text = 'Set folder';
            app.setfolderButton = uibutton(app.LeftPanel, 'push');
            app.setfolderButton.ButtonPushedFcn = createCallbackFcn(app, @setfolderButtonPushed, true);
            app.setfolderButton.Text = 'set folder';
            app.filenameLabel = uilabel(app.LeftPanel);
            app.filenameLabel.Text = 'file format:';
            app.fileformatEditField = uieditfield(app.LeftPanel, 'text');
            app.fileformatEditField.ValueChangedFcn = createCallbackFcn(app, @fileformatEditFieldValueChanged, true);
            app.CenterPanel = uipanel(app.ImageProcessingTab);
            app.ReadimagesLabel_2 = uilabel(app.CenterPanel);
            app.ReadimagesLabel_2.Text = 'Analyze';
            app.analyzeimagesButton = uibutton(app.CenterPanel, 'push');
            app.analyzeimagesButton.ButtonPushedFcn = createCallbackFcn(app, @analyzeimagesButtonPushed, true);
            app.analyzeimagesButton.Text = 'analyze images';
            app.ScalePanel = uipanel(app.CenterPanel);
            app.ScalePanel.Title = 'Scale';
            app.ImagescaleEditFieldLabel = uilabel(app.ScalePanel);
            app.ImagescaleEditFieldLabel.Text = 'Image scale';
            app.ImagescaleEditField = uieditfield(app.ScalePanel, 'numeric');
            app.ImagescaleEditField.RoundFractionalValues = 'on';
            app.ImagescaleEditField.AllowEmpty = 'on';
            app.ImagescaleEditField.ValueChangedFcn = createCallbackFcn(app, @ImagescaleEditFieldValueChanged, true);
            app.scaleisSwitchLabel = uilabel(app.ScalePanel);
            app.scaleisSwitchLabel.Text = 'scale is:';
            app.scaleisSwitch = uiswitch(app.ScalePanel, 'slider');
            app.scaleisSwitch.Items = {'known', 'unknown'};
            app.scaleisSwitch.ValueChangedFcn = createCallbackFcn(app, @scaleisSwitchValueChanged, true);
            app.scaleisSwitch.Value = 'known';
            app.pixelscmLabel = uilabel(app.ScalePanel);
            app.pixelscmLabel.Text = 'pixels/cm';
            app.nclustersSpinnerLabel = uilabel(app.CenterPanel);
            app.nclustersSpinnerLabel.Visible = 'off';
            app.nclustersSpinnerLabel.Text = 'n clusters';
            app.nclustersSpinner = uispinner(app.CenterPanel);
            app.nclustersSpinner.Limits = [2 10];
            app.nclustersSpinner.ValueChangedFcn = createCallbackFcn(app, @nclustersSpinnerValueChanged, true);
            app.nclustersSpinner.Visible = 'off';
            app.nclustersSpinner.Value = 2;
            app.ApproveanalysesButtonGroup = uibuttongroup(app.CenterPanel);
            app.ApproveanalysesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ApproveanalysesButtonGroupSelectionChanged, true);
            app.ApproveanalysesButtonGroup.Title = 'Approve analyses';
            app.approvedButton = uitogglebutton(app.ApproveanalysesButtonGroup);
            app.approvedButton.Text = 'approved';
            app.approvedButton.Value = true;
            app.notapprovedButton = uitogglebutton(app.ApproveanalysesButtonGroup);
            app.notapprovedButton.Text = 'not approved';
            app.unknownButton = uitogglebutton(app.ApproveanalysesButtonGroup);
            app.unknownButton.Text = 'unknown';
            app.RightPanel = uipanel(app.ImageProcessingTab);
            app.Messageboard200mostrecentPanel = uipanel(app.RightPanel);
            app.Messageboard200mostrecentPanel.Title = 'Message board (200 most recent)';
            app.MessageboardTextArea = uitextarea(app.Messageboard200mostrecentPanel);
            app.MessageboardTextArea.Editable = 'off';
            app.EladBenDorButton = uibutton(app.RightPanel, 'push');
            app.EladBenDorButton.ButtonPushedFcn = createCallbackFcn(app, @EladBenDorButtonPushed, true);
            app.EladBenDorButton.Text = 'Elad Ben-Dor';
            app.YoavBenDorButton = uibutton(app.RightPanel, 'push');
            app.YoavBenDorButton.ButtonPushedFcn = createCallbackFcn(app, @YoavBenDorButtonPushed, true);
            app.YoavBenDorButton.Text = 'Yoav Ben Dor';
            app.Hyperlink = uihyperlink(app.RightPanel);
            app.Hyperlink.HyperlinkClickedFcn = createCallbackFcn(app, @HyperlinkClicked, true);
            app.Hyperlink.Tooltip = {'CC BY-NC-SA 4.0'};
            app.Hyperlink.Text = 'License: CC BY-NC-SA 4.0';
            app.Image = uiimage(app.RightPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'PitAnalyzerLogo', 'PitAnalyzerLogo_rect.png');
            app.SynthesisTab = uitab(app.TabGroup);
            app.SynthesisTab.Title = 'Synthesis';
            app.LeftPanel_2 = uipanel(app.SynthesisTab);
            app.exportallfiguresButton = uibutton(app.LeftPanel_2, 'push');
            app.exportallfiguresButton.ButtonPushedFcn = createCallbackFcn(app, @exportallfiguresButtonPushed, true);
            app.exportallfiguresButton.Text = 'export all figures';
            app.FeaturesListBoxLabel = uilabel(app.LeftPanel_2);
            app.FeaturesListBoxLabel.Text = 'Features';
            app.FeaturesListBox = uilistbox(app.LeftPanel_2);
            app.FeaturesListBox.Items = {'sample1', 'sample2', 'sample3'};
            app.FeaturesListBox.Multiselect = 'on';
            app.FeaturesListBox.Value = {'sample1'};
            app.SetfolderLabel_2 = uilabel(app.LeftPanel_2);
            app.SetfolderLabel_2.Text = 'Set references folder';
            app.plotButton = uibutton(app.LeftPanel_2, 'push');
            app.plotButton.ButtonPushedFcn = createCallbackFcn(app, @plotButtonPushed, true);
            app.plotButton.Text = 'plot';
            app.AnalysesKnob = uiknob(app.LeftPanel_2, 'discrete');
            app.AnalysesKnob.Items = {'Boxplots', 'CDF', 'PDE', 'PCA'};
            app.AnalysesKnob.ValueChangedFcn = createCallbackFcn(app, @AnalysesKnobValueChanged, true);
            app.AnalysesKnob.Value = 'Boxplots';
            app.selectallButton_2 = uibutton(app.LeftPanel_2, 'push');
            app.selectallButton_2.ButtonPushedFcn = createCallbackFcn(app, @selectallButton_2Pushed, true);
            app.selectallButton_2.Text = 'select all';
            app.TargetsListBox = uilistbox(app.LeftPanel_2);
            app.TargetsListBox.Items = {'sample1', 'sample2', 'sample3'};
            app.TargetsListBox.Multiselect = 'on';
            app.TargetsListBox.ValueChangedFcn = createCallbackFcn(app, @TargetsListBoxValueChanged, true);
            app.TargetsListBox.Value = {'sample1'};
            app.referencesListBoxLabel = uilabel(app.LeftPanel_2);
            app.referencesListBoxLabel.Text = 'references';
            app.ReferencesListBox = uilistbox(app.LeftPanel_2);
            app.ReferencesListBox.Items = {'sample1', 'sample2', 'sample3'};
            app.ReferencesListBox.Multiselect = 'on';
            app.ReferencesListBox.ValueChangedFcn = createCallbackFcn(app, @ReferencesListBoxValueChanged, true);
            app.ReferencesListBox.Value = {'sample1'};
            app.loadCSVsButton = uibutton(app.LeftPanel_2, 'push');
            app.loadCSVsButton.ButtonPushedFcn = createCallbackFcn(app, @loadCSVsButtonPushed, true);
            app.loadCSVsButton.Text = 'load CSVs';
            app.SetfolderLabel_3 = uilabel(app.LeftPanel_2);
            app.SetfolderLabel_3.Text = 'Set targets folder';
            app.setfolderButton_3 = uibutton(app.LeftPanel_2, 'push');
            app.setfolderButton_3.ButtonPushedFcn = createCallbackFcn(app, @setfolderButton_3Pushed, true);
            app.setfolderButton_3.Text = 'set folder';
            app.savemergeddataButton = uibutton(app.LeftPanel_2, 'push');
            app.savemergeddataButton.ButtonPushedFcn = createCallbackFcn(app, @savemergeddataButtonPushed, true);
            app.savemergeddataButton.Text = 'save merged data';
            app.setfolderButton_2 = uibutton(app.LeftPanel_2, 'push');
            app.setfolderButton_2.ButtonPushedFcn = createCallbackFcn(app, @setfolderButton_2Pushed, true);
            app.setfolderButton_2.Text = 'set folder';
            app.targetsListBoxLabel = uilabel(app.LeftPanel_2);
            app.targetsListBoxLabel.Text = 'targets';
            app.selectallButton_3 = uibutton(app.LeftPanel_2, 'push');
            app.selectallButton_3.ButtonPushedFcn = createCallbackFcn(app, @selectallButton_3Pushed, true);
            app.selectallButton_3.Text = 'select all';
            app.AnalysesKnobLabel = uilabel(app.LeftPanel_2);
            app.AnalysesKnobLabel.Text = 'Analyses';
            app.clearselectionButton = uibutton(app.LeftPanel_2, 'push');
            app.clearselectionButton.ButtonPushedFcn = createCallbackFcn(app, @clearselectionButtonPushed, true);
            app.clearselectionButton.Text = 'clear selection';
            app.clearselectionButton_2 = uibutton(app.LeftPanel_2, 'push');
            app.clearselectionButton_2.ButtonPushedFcn = createCallbackFcn(app, @clearselectionButton_2Pushed, true);
            app.clearselectionButton_2.Text = 'clear selection';
            app.PC1DropDownLabel = uilabel(app.LeftPanel_2);
            app.PC1DropDownLabel.Text = 'PC1';
            app.PC1DropDown = uidropdown(app.LeftPanel_2);
            app.PC1DropDown.ValueChangedFcn = createCallbackFcn(app, @PC1DropDownValueChanged, true);
            app.PC2DropDownLabel = uilabel(app.LeftPanel_2);
            app.PC2DropDownLabel.Text = 'PC2';
            app.PC2DropDown = uidropdown(app.LeftPanel_2);
            app.PC2DropDown.ValueChangedFcn = createCallbackFcn(app, @PC2DropDownValueChanged, true);
            app.breakButton = uibutton(app.LeftPanel_2, 'push');
            app.breakButton.ButtonPushedFcn = createCallbackFcn(app, @breakButtonPushed, true);
            app.breakButton.Text = 'break';
            app.mergerowsbyCheckBox = uicheckbox(app.LeftPanel_2);
            app.mergerowsbyCheckBox.ValueChangedFcn = createCallbackFcn(app, @mergerowsbyCheckBoxValueChanged, true);
            app.mergerowsbyCheckBox.Text = 'merge rows by:';
            app.variableDropDownLabel = uilabel(app.LeftPanel_2);
            app.variableDropDownLabel.Text = 'variable';
            app.variableDropDown = uidropdown(app.LeftPanel_2);
            app.variableDropDown.DropDownOpeningFcn = createCallbackFcn(app, @variableDropDownOpening, true);
            app.variableDropDown.ValueChangedFcn = createCallbackFcn(app, @variableDropDownValueChanged, true);
            app.categoryLabel = uilabel(app.LeftPanel_2);
            app.categoryLabel.Text = 'category';
            app.categoryDropDown = uidropdown(app.LeftPanel_2);
            app.categoryDropDown.ValueChangedFcn = createCallbackFcn(app, @categoryDropDownValueChanged, true);
            app.colorDropDownLabel = uilabel(app.LeftPanel_2);
            app.colorDropDownLabel.Text = 'color';
            app.colorDropDown = uidropdown(app.LeftPanel_2);
            app.colorDropDown.ValueChangedFcn = createCallbackFcn(app, @colorDropDownValueChanged, true);
            app.shapeDropDownLabel = uilabel(app.LeftPanel_2);
            app.shapeDropDownLabel.Text = 'shape';
            app.shapeDropDown = uidropdown(app.LeftPanel_2);
            app.shapeDropDown.ValueChangedFcn = createCallbackFcn(app, @shapeDropDownValueChanged, true);
            app.LeftPanel_3 = uipanel(app.SynthesisTab);
            app.LeftPanel_3.SizeChangedFcn = createCallbackFcn(app, @LeftPanel_3SizeChanged, true);
            app.UIAxes = uiaxes(app.LeftPanel_3);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PitAnalyzerApp2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end